classdef TSADPlatform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TSADPlatformUIFigure            matlab.ui.Figure
        SettingsMenu                    matlab.ui.container.Menu
        EnableParallelModeMenu          matlab.ui.container.Menu
        MainPanel                       matlab.ui.container.TabGroup
        DatasetPreparationTab           matlab.ui.container.Tab
        DatasetPreparationPanel         matlab.ui.container.Panel
        DP_DatasetProcessingPanel       matlab.ui.container.Panel
        DP_AugmentationIntensityLabel   matlab.ui.control.Label
        DP_AugmentationModeDropDown     matlab.ui.control.DropDown
        AugmentationModeDropDownLabel   matlab.ui.control.Label
        DP_PreprocessingMethodDropDown  matlab.ui.control.DropDown
        PreprocessingMethodLabel        matlab.ui.control.Label
        DP_AugmentationIntensitySlider  matlab.ui.control.Slider
        DP_AugmentedTrainingCheckBock   matlab.ui.control.CheckBox
        DP_RatioTestSwitchLabel         matlab.ui.control.Label
        DP_RatioTestSwitchEditField     matlab.ui.control.NumericEditField
        DP_SplitTestSetForDynamicSwitchCheckBox  matlab.ui.control.CheckBox
        DP_RatioTestValLabel            matlab.ui.control.Label
        DP_UseAnomalousValidationSetCheckBox  matlab.ui.control.CheckBox
        DP_RatioTestValEditField        matlab.ui.control.NumericEditField
        DP_DatasetSelectionPanel        matlab.ui.container.Panel
        DP_DatasetFolderEditField       matlab.ui.control.EditField
        DP_DatasetFolderLabel           matlab.ui.control.Label
        DP_BrowseDatasetButton          matlab.ui.control.Button
        DP_LoadDataButton               matlab.ui.control.Button
        DP_DatasetAttributesUITable     matlab.ui.control.Table
        DP_DatasetAttributesLabel       matlab.ui.control.Label
        DP_SelectedFileTestValDropDown  matlab.ui.control.DropDown
        SelectedFileLabel_5             matlab.ui.control.Label
        DP_SelectedChannelTestValDropDown  matlab.ui.control.DropDown
        SelectedChannelLabel_5          matlab.ui.control.Label
        DP_SelectedFileTestDropDown     matlab.ui.control.DropDown
        SelectedFileLabel_4             matlab.ui.control.Label
        DP_SelectedChannelTestDropDown  matlab.ui.control.DropDown
        SelectedChannelLabel_4          matlab.ui.control.Label
        DP_SelectedFileTrainDropDown    matlab.ui.control.DropDown
        SelectedFileLabel_3             matlab.ui.control.Label
        DP_SelectedChannelTrainDropDown  matlab.ui.control.DropDown
        SelectedChannelLabel_3          matlab.ui.control.Label
        DP_PreprocessedTestValDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        DP_PreprocessedTestingDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        DP_PreprocessedTrainingDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        TrainingAndDetectionTab         matlab.ui.container.Tab
        TrainingAndDetectionPanel       matlab.ui.container.Panel
        TD_DetectionPanel               matlab.ui.container.Panel
        TD_OnlineSimulationCheckBox     matlab.ui.control.CheckBox
        TD_UpdateThresholdOnlyCheckBox  matlab.ui.control.CheckBox
        TD_GetComputationTimeCheckBox   matlab.ui.control.CheckBox
        TD_ModelSelectionButtonGroup    matlab.ui.container.ButtonGroup
        TD_ModelSelectionSelectionButton  matlab.ui.control.RadioButton
        TD_ModelSelectionAllButton      matlab.ui.control.RadioButton
        TD_ThresholdDropDown            matlab.ui.control.DropDown
        ThresholdDropDownLabel          matlab.ui.control.Label
        TD_AnomalyPaddingSlider         matlab.ui.control.Slider
        TD_WindowSizeSlider             matlab.ui.control.Slider
        TD_ZRangeSlider                 matlab.ui.control.Slider
        TD_AnomalyPaddingLabel          matlab.ui.control.Label
        TD_WindowSizeLabel              matlab.ui.control.Label
        TD_ZRangeLabel                  matlab.ui.control.Label
        TD_RunDetectionButton           matlab.ui.control.Button
        TD_FileSelectionButtonGroup     matlab.ui.container.ButtonGroup
        TD_TestDataFileDropDown         matlab.ui.control.DropDown
        TD_FileSelectionFileLabel       matlab.ui.control.Label
        TD_FileSelectionSelectionButton  matlab.ui.control.RadioButton
        TD_FileSelectionAllFilesButton  matlab.ui.control.RadioButton
        TD_FileSelectionTinyDataPreviewUIAxes  matlab.ui.control.UIAxes
        TD_DetectionTabGroup            matlab.ui.container.TabGroup
        TD_PlotsTab                     matlab.ui.container.Tab
        TD_DetectionPerformanceMetricsLabel  matlab.ui.control.Label
        TD_DetectionShownForLabel       matlab.ui.control.Label
        TD_PlotsScoresUITable           matlab.ui.control.Table
        TD_PlotsDataUIAxes              matlab.ui.control.UIAxes
        TD_PlotsAnomalyScoresUIAxes     matlab.ui.control.UIAxes
        TD_TableTab                     matlab.ui.container.Tab
        TD_ScoresLegendLabel            matlab.ui.control.Label
        TD_ScoresColumnwiseMaximaLabel  matlab.ui.control.Label
        TD_ScoresRowwiseMaximaLabel     matlab.ui.control.Label
        TD_ScoresExportTableButton      matlab.ui.control.Button
        TD_ScoresUITable                matlab.ui.control.Table
        TD_AverageScoresTab             matlab.ui.container.Tab
        TD_AverageScoresLegendLabel     matlab.ui.control.Label
        TD_AverageScoresColumnwiseMaximaLabel  matlab.ui.control.Label
        TD_AverageScoresRowwiseMaximaLabel  matlab.ui.control.Label
        TD_AverageScoresExportTableButton  matlab.ui.control.Button
        TD_AverageScoresUITable         matlab.ui.control.Table
        TD_ComputationTimeTab           matlab.ui.container.Tab
        TD_CompTimeMetricSelectionButton  matlab.ui.control.Button
        TD_CompTimeUIAxes               matlab.ui.control.UIAxes
        TD_ModelsPanel                  matlab.ui.container.Panel
        TD_ExportConfigButton           matlab.ui.control.Button
        TD_ClearAllButton               matlab.ui.control.Button
        TD_FitAllButton                 matlab.ui.control.Button
        TD_FitSelectionButton           matlab.ui.control.Button
        TD_TrainingPlotsCheckBox        matlab.ui.control.CheckBox
        TD_OptimizeCheckBox             matlab.ui.control.CheckBox
        TD_LoadManuallyButton           matlab.ui.control.Button
        TD_LoadDefaultButton            matlab.ui.control.Button
        TD_LoadConfigButton             matlab.ui.control.Button
        TD_SortByDropDown               matlab.ui.control.DropDown
        SortbyLabel                     matlab.ui.control.Label
        TD_ImportTrainedButton          matlab.ui.control.Button
        TD_ExportTrainedButton          matlab.ui.control.Button
        TD_ModelsListBox                matlab.ui.control.ListBox
        DynamicSwitchTab                matlab.ui.container.Tab
        DynamicSwitchPanel              matlab.ui.container.Panel
        DS_SelectedFileTestSwitchDropDown  matlab.ui.control.DropDown
        SelectedFileLabel_6             matlab.ui.control.Label
        DS_SelectedChannelTestSwitchDropDown  matlab.ui.control.DropDown
        SelectedChannelLabel_6          matlab.ui.control.Label
        DS_ExportButton                 matlab.ui.control.Button
        DS_TrainingAndEvaluationPanel   matlab.ui.container.Panel
        DS_TestingDataPredictionsLabel  matlab.ui.control.Label
        DS_TestingDataPredictionsUITable  matlab.ui.control.Table
        DS_TrainingDataLabelsLabel      matlab.ui.control.Label
        DS_TrainingDataLabelsUITable    matlab.ui.control.Table
        DS_MetricDropDown               matlab.ui.control.DropDown
        ScoreforAutolabelingLabel       matlab.ui.control.Label
        DS_TrainClassifierButton        matlab.ui.control.Button
        DS_RunEvaluationsButton         matlab.ui.control.Button
        DS_ResultsLabel                 matlab.ui.control.Label
        DS_ResultsUITable               matlab.ui.control.Table
        DS_PreprocessedTestSwitchDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        AutoRunTab                      matlab.ui.container.Tab
        AutoRunPanel                    matlab.ui.container.Panel
        AR_ExportTableButton            matlab.ui.control.Button
        AR_ResultsLabel                 matlab.ui.control.Label
        AR_ResultsUITable               matlab.ui.control.Table
        AR_AutoTrainingAndEvaluationPanel  matlab.ui.container.Panel
        AR_GetComputationTimeCheckBox   matlab.ui.control.CheckBox
        AR_VisualizeDataButton          matlab.ui.control.Button
        AR_AugmentationModeDropDown     matlab.ui.control.DropDown
        AugmentationModeDropDownLabel_2  matlab.ui.control.Label
        AR_PreprocessingMethodDropDown  matlab.ui.control.DropDown
        PreprocessingMethodLabel_2      matlab.ui.control.Label
        AR_IntensityLabel               matlab.ui.control.Label
        AR_IntensitySlider              matlab.ui.control.Slider
        AR_AugmentedTrainingCheckBock   matlab.ui.control.CheckBox
        AR_TrainingPlotsCheckBox        matlab.ui.control.CheckBox
        AR_RatioTestValLabel            matlab.ui.control.Label
        AR_UseAnomalousValidationSetCheckBox  matlab.ui.control.CheckBox
        AR_RatioTestValEditField        matlab.ui.control.NumericEditField
        AR_DatasetFolderEditField       matlab.ui.control.EditField
        AR_DatasetFolderLabel           matlab.ui.control.Label
        AR_BrowseDatasetButton          matlab.ui.control.Button
        AR_RunEvaluationButton          matlab.ui.control.Button
        TD_ContextMenu                  matlab.ui.container.ContextMenu
        TD_ViewUpdateModelParametersMenu  matlab.ui.container.Menu
        TD_ShowDetectionMenu            matlab.ui.container.Menu
        TD_ChangeScoringFunctionMenu    matlab.ui.container.Menu
        TD_UpdateStaticThresholdsMenu   matlab.ui.container.Menu
        TD_DeleteMenu                   matlab.ui.container.Menu
    end

    
    properties (Access = private)
        %% App Status Information
        info_dataLoaded = false                     % True if data was loaded
        info_trainDataLoaded = false;               % True if train data was loaded
        info_testDataLoaded = false;                % True if test data was loaded
        info_numTrainFiles = 0;                     % Number of training files
        info_numTestFiles = 0;                      % Number of all testing files
        info_numChannels = false;                   % Number of channels in dataset
        info_detectionShowing = false;              % True if a detection is shown in the plots on the detection panel
        info_dimensionMismatchDetected = false;     % true if dataset diemsnion doesn't match dimension of model in runDetectionFor function.
                                                    % This is only used for
                                                    % uialert message handling
        %%

        %% Data
        data_channelNames                           % Names of the channels

        data_dataTrainRaw                           % Initial fault free raw data
        data_dataTestRaw                            % Initial faulty raw data
        

        data_fileNamesTrain                         % Array of filenames of the training data
        data_fileNamesTestAll                       % Array of filenames of the entire testing dataset
        data_fileNamesTest                          % Array of filenames of the final testing data (after splitting the dataset) 
        data_fileNamesTestVal                       % Array of filenames of the anomalous validation data
        data_fileNamesTestSwitch                    % Array of filenames of the dynamic switch data
        data_allTestFileNames                       % Array of filenames of all test files without the dynamic switch files

        data_labelsTrain                            % Training labels   
        data_labelsTestAll                          % Labels for all test files
        data_labelsTest                             % Labels for final testing data (after splitting the dataset) 
        data_labelsTestVal                          % Labels for anomalous validation data
        data_labelsTestSwitch                       % Labels for dynamic switch data
        data_allTestLabels                          % Labels for all test files without the dynamic switch labels

        data_dataTrain                              % Preprocessed fault free data
        data_dataTestAll                            % All testing data
        data_dataTest                               % Final testing data (after splitting the dataset) 
        data_dataTestVal                            % Anomalous validation data
        data_dataTestSwitch                         % Dynamic switch test data
        data_allTestData                            % All test data without the dynamic switch data
        
        % These timestamps aren't used in the platform, but could be of interest for
        % future upgrades
        data_timestampsTrain                        % Timestamps of training data
        data_timestampsTestAll                      % Timestamps of all test data
        data_timestampsTest                         % Timestamps of the final testing data (after splitting the dataset) 
        data_timestampsTestSwitch                   % Timestamps of the dynamic switch data
        data_timestampsTestVal                      % Timestamps of the anoamlous validation data
        data_allTestTimestamps                      % Timestamps of all test files without the dynmaic switch data

        % Channel-wise dataset features
        data_maximum                                % Maximum values of data
        data_minimum                                % Minimum values of data
        data_mean                                   % Means of training data
        data_standardDeviation                      % Standard deviations data
        %%

        %% Models
        Models = struct();%new
        trainedModels = struct();                   % Trained models struct

        %% Settings
        settings_parallelEnabled = false;           % If true, training will be run in parallel
        settings_dynamicThreshold                   % Dynamic threshold settings

        %% Detection
        currentDetection_modelID                    % Contains modelID for model that is currently shown in the plots on the detection panel
        
        fullScores = struct();                      % All scores for all tested files
        averageScores = table();                    % Average scores for all models for all files
        
        detectionStorage = struct();                % Stores all test anomaly scores, timeSeriesTest (tested time series) labels and thresholds for each file and model the detection has been run for

        compTimeMetrics = ["F1 Score (pointwise)"]; % Selected metrics shown in the computation time plot
        %%
        
        %% Dynamic Switch
        ds_classifier                               % Classification network for the dynamic switch
        ds_bestModels = struct();                   % Labels for training the dynamic switch

        %% Popup Apps
        popup_ModelSelection                        % Popup for configuring models
        popup_ModelParametersViewAndUpdate          % Popup for viewing and updating model parameters
        popup_OptimizationConfigurator              % Popup for optimizing models
        popup_ThresholdSelection                    % Popup for selecting thresholds
        popup_CompTimeMetricSelection               % Popup for selecting the computation time metrics
        popup_DatasetVisualization                  % Popup for visualizing the global transformation
        %%

        %% UI dialog
        progressDialog
        %%
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
%% Toggle functions for disabling/enabling elements

        function disableAxesFor(~, UIAxes)
            %DISABLEAXES Disables axes for UIAxes

            for i = 1:numel(UIAxes)
                axis(UIAxes(i), "off");
            end
        end

        function initSortbyDropdown(app)
            %INITSORTBYDROPDOWNS Init sortby dropdown menu items on detection panel

            app.TD_SortByDropDown.Items = METRIC_NAMES;
            app.DS_MetricDropDown.Items = METRIC_NAMES;
        end

        function initThresholdDropdowns(app)
            %INITTHRESHOLDDROPDOWNS Init threshold dropdown items

            items = THRESHOLD_NAMES;
            app.TD_ThresholdDropDown.Items = items;
        end

        function initDynamicThresholdSettings(app)
            %INITDYNAMICTHRESHOLDSETTINGS Init dynamic threshold settings

            app.settings_dynamicThreshold.windowSize = 50;
            app.settings_dynamicThreshold.anomalyPadding = 30;
            app.settings_dynamicThreshold.zRange = 5;
            app.settings_dynamicThreshold.minPercent = 1;

            app.TD_WindowSizeSlider.Value = app.settings_dynamicThreshold.windowSize;
            app.TD_AnomalyPaddingSlider.Value = app.settings_dynamicThreshold.anomalyPadding;
            app.TD_ZRangeSlider.Value = app.settings_dynamicThreshold.zRange;
        end
        
        function toggleDynamicSwitchPanel(app, state)
            %TOGGLEDYNAMICSWITCHPANEL Toggles the dynamic switch panel

            if state
                app.DS_TrainClassifierButton.Enable = "on";
                app.DS_RunEvaluationsButton.Enable = "on";
            else
                app.DS_TrainClassifierButton.Enable = "off";
                app.DS_RunEvaluationsButton.Enable = "off";
            end
        end

        function toggleDynamicThresholdSliders(app, threshold)
            %TOGGLEDYNAMICTHRESHOLDSLIDERS Toggles the sliders for configuring the dynamic threshold

            if strcmp(threshold, "Dynamic")
                app.TD_AnomalyPaddingSlider.Enable = "on";
                app.TD_AnomalyPaddingLabel.Enable = "on";
                app.TD_WindowSizeSlider.Enable = "on";
                app.TD_WindowSizeLabel.Enable = "on";
                app.TD_ZRangeSlider.Enable = "on";
                app.TD_ZRangeLabel.Enable = "on";
            else
                app.TD_AnomalyPaddingSlider.Enable = "off";
                app.TD_AnomalyPaddingLabel.Enable = "off";
                app.TD_WindowSizeSlider.Enable = "off";
                app.TD_WindowSizeLabel.Enable = "off";
                app.TD_ZRangeSlider.Enable = "off";
                app.TD_ZRangeLabel.Enable = "off";
            end
        end

%% Dataset preview plotting functions
        
        function plotLoadedData(app, dataset, file_idx, channel_idx)
            %PLOTLOADEDDATA Plotting of dataset

            switch dataset
                case "train"
                    axes = app.DP_PreprocessedTrainingDatasetPreviewUIAxes;
                    data = app.data_dataTrain{file_idx}(:, channel_idx);
                case "test"
                    axes = app.DP_PreprocessedTestingDatasetPreviewUIAxes;
                    data = app.data_dataTest{file_idx}(:, channel_idx);
                case "test-val"
                    axes = app.DP_PreprocessedTestValDatasetPreviewUIAxes;
                    data = app.data_dataTestVal{file_idx}(:, channel_idx);
                case "test-switch"
                    axes = app.DS_PreprocessedTestSwitchDatasetPreviewUIAxes;
                    data = app.data_dataTestSwitch{file_idx}(:, channel_idx);
            end
            
            cla(axes);
            plot(axes, data);
        end

        function updatePreviewData(app, dataset)
            %UPDATEPREVIEWDATA Update plots of dataset

            switch dataset
                case "train"
                    file = app.DP_SelectedFileTrainDropDown.Value;
                    [~, file_idx] = ismember(file, app.DP_SelectedFileTrainDropDown.Items);
                    channel = app.DP_SelectedChannelTrainDropDown.Value;
                    [~, channel_idx] = ismember(channel, app.DP_SelectedChannelTrainDropDown.Items);
                case "test"
                    file = app.DP_SelectedFileTestDropDown.Value;
                    [~, file_idx] = ismember(file, app.DP_SelectedFileTestDropDown.Items);
                    channel = app.DP_SelectedChannelTestDropDown.Value;
                    [~, channel_idx] = ismember(channel, app.DP_SelectedChannelTestDropDown.Items);
                case "test-val"
                    file = app.DP_SelectedFileTestValDropDown.Value;
                    [~, file_idx] = ismember(file, app.DP_SelectedFileTestValDropDown.Items);
                    channel = app.DP_SelectedChannelTestValDropDown.Value;
                    [~, channel_idx] = ismember(channel, app.DP_SelectedChannelTestValDropDown.Items);
                case "test-switch"
                    file = app.DS_SelectedFileTestSwitchDropDown.Value;
                    [~, file_idx] = ismember(file, app.DS_SelectedFileTestSwitchDropDown.Items);
                    channel = app.DS_SelectedChannelTestSwitchDropDown.Value;
                    [~, channel_idx] = ismember(channel, app.DS_SelectedChannelTestSwitchDropDown.Items);
            end

            app.plotLoadedData(dataset, file_idx, channel_idx);
            app.createPreviewLegend(dataset, file_idx);
        end

        function createPreviewLegend(app, dataset, file_idx)
            %CREATEPREVIEWLEGEND Creates legend for dataset axes

            switch dataset
                case "train"
                    axes = app.DP_PreprocessedTrainingDatasetPreviewUIAxes;
                    anomalies_indices = getAnomalyIndices(app.data_labelsTrain{file_idx});
                case "test"
                    axes = app.DP_PreprocessedTestingDatasetPreviewUIAxes;
                    anomalies_indices = getAnomalyIndices(app.data_labelsTest{file_idx});
                case "test-val"
                    axes = app.DP_PreprocessedTestValDatasetPreviewUIAxes;
                    anomalies_indices = getAnomalyIndices(app.data_labelsTestVal{file_idx});
                case "test-switch"
                    axes = app.DS_PreprocessedTestSwitchDatasetPreviewUIAxes;
                    anomalies_indices = getAnomalyIndices(app.data_labelsTestSwitch{file_idx});
            end
            
            YLims = get(axes, "YLim");
            limits = [];
            for i = 1:size(anomalies_indices, 2)
                limits(1, i) = YLims(1);
                limits(2, i) = YLims(1);
                limits(3, i) = YLims(2);
                limits(4, i) = YLims(2);
            end
            
            if ~isempty(anomalies_indices) && ~isempty(limits)
                patch(axes, anomalies_indices, limits, "red", "EdgeColor", "none", "FaceAlpha", 0.3);
                legend(axes, "Data", "Anomalies");
            else
                legend(axes, "Data");
            end
        end   

%% Helper functions

        function highlightTableMaxima(~, UITable, type, rowIncrement)
            %HIGHLIGHTTABLEMAXIMA Highlights maxima in table

            if ~exist("rowIncrement", "var")
                rowIncrement = 0;
            end

            scoreMatrix = UITable.Data{(1 + rowIncrement):end, 2:end};

            if strcmp(type, "row-wise") || strcmp(type, "all")
                % Add bold highlight for rowise maxima
                styleBold = uistyle;
                styleBold.FontWeight = "bold";
                
                rows_max_rowwise = [];
                columns_max_rowwise = [];
    
                for i = 1:size(scoreMatrix, 1)
                    [~, col] = find(scoreMatrix(i, :) == max(scoreMatrix(i, :), [], 2));
                    
                    for j = 1:numel(col)
                        rows_max_rowwise = [rows_max_rowwise; i];
                        columns_max_rowwise = [columns_max_rowwise; col(j) + 1];
                    end
                end
                
                if ~isempty(rows_max_rowwise) && ~isempty(columns_max_rowwise)
                    rows_max_rowwise = rows_max_rowwise + rowIncrement;
                    addStyle(UITable, styleBold, "cell", [rows_max_rowwise, columns_max_rowwise]);
                end
            end

            if strcmp(type, "column-wise") || strcmp(type, "all")
                % Add green background highlight for column-wise maxima
                styleGreen = uistyle;
                styleGreen.BackgroundColor = "#86F2BC";
    
                rows_max_columnwise = [];
                columns_max_columnwise = [];
    
                for i = 1:size(scoreMatrix, 2)
                    [row, ~] = find(scoreMatrix(:, i) == max(scoreMatrix(:, i), [], 1));
                    
                    for j = 1:numel(row)
                        rows_max_columnwise = [rows_max_columnwise; row(j)];
                        columns_max_columnwise = [columns_max_columnwise; i + 1];
                    end
                end
                
                if ~isempty(rows_max_columnwise) && ~isempty(columns_max_columnwise)
                    rows_max_columnwise = rows_max_columnwise + rowIncrement;
                    addStyle(UITable, styleGreen, "cell", [rows_max_columnwise, columns_max_columnwise]);
                end
            end
        end
        
%% Dataset preparation panel functions

        function loadDataset(app, path)
            %LOADDATASET Loads a dataset

            [app.data_dataTrainRaw, ...
                 app.data_timestampsTrain, ...
                 app.data_labelsTrain, ...
                 app.data_fileNamesTrain, ...
                 app.data_dataTestRaw, ...
                 app.data_allTestTimestamps, ...
                 app.data_allTestLabels, ...
                 app.data_allTestFileNames, ...
                 app.data_channelNames] = loadCustomDataset(path);
            
            % Reset data related elements
            app.clearDatasetPlots();
            app.clearDynamicSwitchData();
            app.clearDetectionPlots();

            app.info_numTrainFiles = numel(app.data_fileNamesTrain);
            app.info_numTestFiles = numel(app.data_allTestFileNames);
            
            % Enable / disable dynamic switch depending of number of
            % testing files
            if app.info_numTestFiles < 2
                app.DP_SplitTestSetForDynamicSwitchCheckBox.Enable = "off";
                app.DP_RatioTestSwitchEditField.Enable = "off";
                app.DP_RatioTestSwitchLabel.Enable = "off";
                app.DP_SplitTestSetForDynamicSwitchCheckBox.Value = false;
                app.toggleDynamicSwitchPanel(false);
            else
                app.DP_SplitTestSetForDynamicSwitchCheckBox.Enable = "on";
                if app.DP_SplitTestSetForDynamicSwitchCheckBox.Value
                    app.DP_RatioTestSwitchEditField.Enable = "on";
                    app.DP_RatioTestSwitchLabel.Enable = "on";
                    app.toggleDynamicSwitchPanel(true);
                else
                    app.DP_RatioTestSwitchEditField.Enable = "off";
                    app.DP_RatioTestSwitchLabel.Enable = "off";
                    app.toggleDynamicSwitchPanel(false);
                end
            end
            
            % init train data info and data preparation plots
            if ~isempty(app.data_dataTrainRaw)
                app.info_numChannels = size(app.data_dataTrainRaw{1}, 2);

                app.DP_SelectedFileTrainDropDown.Items = app.data_fileNamesTrain;
                
                app.info_dataLoaded = true;
                app.info_trainDataLoaded = true;
            else
                app.info_trainDataLoaded = false;
            end
            
            % init test data info and data preparation plots
            if ~isempty(app.data_dataTestRaw)
                app.info_numChannels = size(app.data_dataTestRaw{1}, 2);

                app.DP_SelectedFileTestDropDown.Items = app.data_allTestFileNames;

                app.info_dataLoaded = true;
                app.info_testDataLoaded = true;
            else
                app.info_testDataLoaded = false;
            end
        end
        
        function clearDatasetPlots(app)
            %CLEARDATASETPLOTS Reset data related plots

            cla(app.DP_PreprocessedTrainingDatasetPreviewUIAxes);
            cla(app.DP_PreprocessedTestingDatasetPreviewUIAxes);
            cla(app.DP_PreprocessedTestValDatasetPreviewUIAxes);
            cla(app.DS_PreprocessedTestSwitchDatasetPreviewUIAxes);
        end

        function clearDynamicSwitchData(app)
            %CLEARDYNAMICSWITCHDATA Clear data relvenat for dynamic switch

            app.ds_classifier = [];
            app.ds_bestModels = [];
            app.DS_TrainingDataLabelsUITable.Data = [];
            app.DS_TestingDataPredictionsUITable.Data = [];
        end

        function preprocessAndSplitDataset(app, preprocessingEnabled)
            %PREPROCESSANDSPLITDATASET Processes the entire dataset and
            %splits it into specified subsets
            %
            % If just dataset splitting is updated set preprocessingEnabled
            % to false

            if ~app.info_dataLoaded
                return;
            end
           
            
            % Preprocess data if enabled
            if preprocessingEnabled
                % All preprocessing steps except for dataset splitting
                % tasks are executed in here. preprocessingEnabled must be
                % set to true to enable this!
                
                preprocMethod = app.DP_PreprocessingMethodDropDown.Value;
                
                % Preprocessing
                [app.data_dataTrain, app.data_allTestData, ~] = preprocessData(app.data_dataTrainRaw, ...
                                                                               app.data_dataTestRaw, ...
                                                                               preprocMethod, false, []);
                
                % Augmentation
                [app.data_dataTrain, app.data_allTestData] = augmentData(app.data_dataTrain, ...
                                                                         app.data_allTestData, ...
                                                                         app.DP_AugmentationModeDropDown.Value, ...
                                                                         app.DP_AugmentationIntensitySlider.Value, ...
                                                                         app.DP_AugmentedTrainingCheckBock.Value);
            end

            
            % Split data into subsets
            if app.DP_SplitTestSetForDynamicSwitchCheckBox.Value && app.info_numTestFiles > 1
                ratioSwitch = app.DP_RatioTestSwitchEditField.Value;
                [app.data_dataTestAll, ...
                    app.data_labelsTestAll, ...
                    app.data_fileNamesTestAll, ...
                    app.data_dataTestSwitch, ...
                    app.data_labelsTestSwitch, ...
                    app.data_fileNamesTestSwitch] = splitDatasetForDynamicSwitch(app.data_allTestData, ...
                                                                                 app.data_allTestLabels, ...
                                                                                 app.data_allTestFileNames, ...
                                                                                 ratioSwitch);
            else
                app.data_dataTestAll = app.data_allTestData;
                app.data_labelsTestAll = app.data_allTestLabels;
                app.data_fileNamesTestAll = app.data_allTestFileNames;

                app.data_dataTestSwitch = [];
                app.data_labelsTestSwitch = [];
                app.data_fileNamesTestSwitch = [];
            end

            if app.DP_UseAnomalousValidationSetCheckBox.Value
                ratioTestVal = app.DP_RatioTestValEditField.Value;
            else
                ratioTestVal = 0;
            end

            
            if ~isempty(app.data_dataTestAll)
                [app.data_dataTest, ...
                    app.data_labelsTest, ...
                    app.data_fileNamesTest, ...
                    app.data_dataTestVal, ...
                    app.data_labelsTestVal, ...                    
                    app.data_fileNamesTestVal] = splitTestVal(app.data_dataTestAll, ...
                                                              app.data_labelsTestAll, ...
                                                              app.data_fileNamesTestAll, ...
                                                              ratioTestVal);
            else
                app.data_dataTest = [];
                app.data_labelsTest = [];
                app.data_fileNamesTest = [];
                app.data_dataTestVal = [];
                app.data_labelsTestVal = [];
                app.data_fileNamesTestVal = [];
            end

            app.updateDataSpecificUIElements();
            app.updateTinyDataPreview();    
            app.updateStorage();
            app.clearDetectionPlots();
            app.updateTrainingAndDetectionPanel();
        end

        function updateDataSpecificUIElements(app)
            %UPDATEDATASPECIFICUIELEMENTS Updates ui elements which change
            %if the dataset changes/is split differently

            if ~isempty(app.data_fileNamesTrain)
                app.DP_SelectedFileTrainDropDown.Items = app.data_fileNamesTrain;
            end

            if ~isempty(app.data_fileNamesTest)
                app.DP_SelectedFileTestDropDown.Items = app.data_fileNamesTest;
            end

            if ~isempty(app.data_fileNamesTestVal)
                app.DP_SelectedFileTestValDropDown.Items = app.data_fileNamesTestVal;
            else
                app.DP_SelectedFileTestValDropDown.Items = {};
            end

            if ~isempty(app.data_fileNamesTestSwitch)
                app.DS_SelectedFileTestSwitchDropDown.Items = app.data_fileNamesTestSwitch;
            else
                app.DS_SelectedFileTestSwitchDropDown.Items = {};
            end
            
            if ~isempty(app.data_fileNamesTest)
                app.TD_TestDataFileDropDown.Items = app.data_fileNamesTest;
            else
                app.TD_TestDataFileDropDown.Items = {};

                cla(app.TD_FileSelectionTinyDataPreviewUIAxes);
            end
            
            if ~isempty(app.data_dataTrain)
                app.updatePreviewData("train");
            end

            if ~isempty(app.data_dataTest)
                app.updatePreviewData("test");
            end

            if ~isempty(app.data_dataTestVal)
                app.DP_SelectedChannelTestValDropDown.Enable = "on";
                app.DP_SelectedFileTestValDropDown.Enable = "on";

                app.updatePreviewData("test-val");
            else
                app.DP_SelectedChannelTestValDropDown.Enable = "off";
                app.DP_SelectedFileTestValDropDown.Enable = "off";

                cla(app.DP_PreprocessedTestValDatasetPreviewUIAxes);
            end

            if ~isempty(app.data_dataTestSwitch)
                app.DynamicSwitchPanel.Enable = "on";

                app.updatePreviewData("test-switch");
            else
                app.DynamicSwitchPanel.Enable = "off";

                cla(app.DS_PreprocessedTestSwitchDatasetPreviewUIAxes);
            end
        end

        function updateDataAugmentationSlider(app)
            %UPDATEDATAAUGMENTATIONSLIDER Changes data augmentation slider
            %according to selected agumentation mode

            mode = app.DP_AugmentationModeDropDown.Value;

            switch mode
                case "none"
                    app.DP_AugmentationIntensitySlider.Visible="off";
                    app.DP_AugmentationIntensityLabel.Visible="off";
                    app.DP_AugmentedTrainingCheckBock.Visible = "off";
                case "white_noise"
                    app.DP_AugmentationIntensitySlider.Limits = [0, 1];
                    app.DP_AugmentationIntensityLabel.Text = "Standard deviation";
                    app.DP_AugmentationIntensitySlider.Visible="on";
                    app.DP_AugmentationIntensityLabel.Visible="on";
                    app.DP_AugmentedTrainingCheckBock.Visible = "on";
                case "random_walk"
                    app.DP_AugmentationIntensitySlider.Limits = [0, 1];
                    app.DP_AugmentationIntensityLabel.Text = "Standard deviation";
                    app.DP_AugmentationIntensitySlider.Visible="on";
                    app.DP_AugmentationIntensityLabel.Visible="on";
                    app.DP_AugmentedTrainingCheckBock.Visible = "on";
                case "global_shift"
                    app.DP_AugmentationIntensitySlider.Limits = [-100, 100];
                    app.DP_AugmentationIntensityLabel.Text = "Shift in % of the max value";
                    app.DP_AugmentationIntensitySlider.Visible="on";
                    app.DP_AugmentationIntensityLabel.Visible="on";
                    app.DP_AugmentedTrainingCheckBock.Visible = "on";
                case "attenuate_extremum"
                    app.DP_AugmentationIntensitySlider.Limits = [0, 100];
                    app.DP_AugmentationIntensityLabel.Text = "Strength of the transformation in %";
                    app.DP_AugmentationIntensitySlider.Visible="on";
                    app.DP_AugmentationIntensityLabel.Visible="on";
                    app.DP_AugmentedTrainingCheckBock.Visible = "on";                    
            end
        end
        
        function showDatasetParameters(app)
            %SHOWDATASETPARAMETERS Initialize channel selection dropdown menus for
            % preview

            app.DP_SelectedChannelTrainDropDown.Items = app.data_channelNames;
            app.DP_SelectedChannelTestDropDown.Items = app.data_channelNames;
            app.DP_SelectedChannelTestValDropDown.Items = app.data_channelNames;
            app.DS_SelectedChannelTestSwitchDropDown.Items = app.data_channelNames;
            
            % Calc statistical data features
            if ~isempty(app.data_dataTrainRaw)
                fullData = [];
                for i = 1:numel(app.data_dataTrainRaw)
                    fullData = [fullData; app.data_dataTrainRaw{i}];
                end
            elseif ~isempty(app.data_dataTestRaw)
                fullData = [];
                for i = 1:numel(app.data_dataTestRaw)
                    fullData = [fullData; app.data_dataTestRaw{i}];
                end
            end

            [~, app.data_mean, app.data_standardDeviation] = zscore(fullData, 0, 1);
            app.data_minimum = min(fullData, [], 1);
            app.data_maximum = max(fullData, [], 1);

            % Show features of data
            app.DP_DatasetAttributesUITable.Data = [app.data_channelNames', ...
                                                   app.data_minimum', ...
                                                   app.data_maximum', ...
                                                   app.data_mean', ...
                                                   app.data_standardDeviation'];
        end

%% Training panel functions
        
        function fitModels(app, modelIDs)
            %FITMODELS Trains the models
            %
            % Arguments: models - the IDs of the models to traing (E.g.TSAD_FC_AE_1)
            
            if app.TD_TrainingPlotsCheckBox.Value
                trainingPlots = "training-progress";
            else
                trainingPlots = "none";
            end
            
            for i = 1:numel(modelIDs)
                % Call training wrapper function on model object
                app.Models.(modelIDs{i}) = app.Models.(modelIDs{i}).trainingWrapper(app.data_dataTrain, ...
                                                                                app.data_labelsTrain, ...
                                                                                app.data_dataTestVal, ...
                                                                                app.data_labelsTestVal, ...
                                                                                trainingPlots, ...
                                                                                true);

                % Remove previous detections of models from storage. This
                % does nothing if the model is trained for the first time.
                app.removePreviousDetectionsOf(modelIDs{i});
            end

            app.updateStorage();
            app.updateTrainingAndDetectionPanel();
        end

        function intanceNumber = getModelInstanceNumber(app, modelName)
            %GETMODELINSTANCENUMBER Gets the first free instance number for
            %the model class
            
            % Get all used instance numbers for model class
            usedInstanceNumbers = [];
            existingModels = fieldnames(app.Models);
            for i = 1:numel(existingModels)
                if strcmp(modelName, app.Models.(existingModels{i}).parameters.name)
                    usedInstanceNumbers = [usedInstanceNumbers, app.Models.(existingModels{i}).instanceInfo.instanceNumber];
                end
            end
            
            % Compute first free instance number
            intanceNumber = 1;
            while true
                if ismember(intanceNumber, usedInstanceNumbers)
                    intanceNumber = intanceNumber + 1;
                    continue;
                else
                    break;
                end
            end
        end
        
        function graph = createModelGraphForPreview(~, modelOptions)
            % Creates the model graph of a DNN

            layers = getLayers(modelOptions, {""}, {""});
            a = layers.Connections;
            b = extractBefore(a.Source, "/");
            c = extractBefore(a.Destination, "/");
            for i = 1:size(a, 1)
                if ~isempty(b{i})
                    a.Source{i} = b{i};
                end
                if ~isempty(c{i})
                    a.Destination{i} = c{i};
                end
            end
            EndNodes = [a.Source a.Destination];
            nodeTable = table(EndNodes);
            graph = digraph(nodeTable);
        end

        function showModelPreview(app, modelOptions)
            % Shows the parameters of a selected model

            app.T_NoModelLoadedforPreviewLabel.Visible = "off";

            cla(app.T_PreviewUIAxes);

            app.T_PreviewGeneralInformationUITable.Data = [];
            app.T_PreviewHyperparametersUITable.Data = [];

            newData = [];

            newData = [newData; {"Name:", modelOptions.name}];
            newData = [newData; {"Type:", modelOptions.type}];
            newData = [newData; {"Learning Type:", modelOptions.learningType}];

            if isfield(modelOptions, "modelType")
                newData = [newData; {"Model Type:", modelOptions.modelType}];
            end

            if isfield(modelOptions, "dimensionality")
                newData = [newData; {"Dimensionality:", modelOptions.dimensionality}];
            end

            if isfield(modelOptions, "useSlidingWindow")
                if modelOptions.useSlidingWindow
                    newData = [newData; {"Use sliding Window:", "yes"}];
                else
                    newData = [newData; {"Use sliding Window:", "no"}];
                end
            end

            app.T_PreviewGeneralInformationUITable.Data = table(newData);

            if isfield(modelOptions, "hyperparameters")
                hyperparametersData = [];
                paramNames = fieldnames(modelOptions.hyperparameters);
                for i = 1:numel(paramNames)
                    hyperparametersData = [hyperparametersData; {sprintf("%s:", paramNames{i}), string(modelOptions.hyperparameters.(paramNames{i}))}];
                end
                app.T_PreviewHyperparametersUITable.Data = table(hyperparametersData);
            end


            if strcmp(modelOptions.type, "deep-learning")
                graph = app.createModelGraphForPreview(modelOptions);
                plot(app.T_PreviewUIAxes, graph);
                app.T_PreviewUIAxes.Title.String = "Network Architecture";
            else
                app.T_PreviewUIAxes.Title.String = "";
            end

            app.T_PreviewGeneralInformationUITable.Visible = "on";
            app.T_PreviewHyperparametersUITable.Visible = "on";
            app.T_PreviewShowingLabel.Text = sprintf("Showing: %s", modelOptions.label);
            app.T_PreviewShowingLabel.Visible = "on";
        end

%% Detection panel functions

        function runDetection(app)
            %RUNDETECTION Runs the detection using the models (= main testing
            %function)

            getComputationTime = app.TD_GetComputationTimeCheckBox.Value;
            updateThresholdsOnly = app.TD_UpdateThresholdOnlyCheckBox.Value;
            
            % Get all files to be tested
            if app.TD_FileSelectionButtonGroup.SelectedObject == app.TD_FileSelectionAllFilesButton
                files = app.data_fileNamesTest;
            else
                files = app.TD_TestDataFileDropDown.Value;
            end
            
            % Get all models to be tested
            if app.TD_ModelSelectionButtonGroup.SelectedObject == app.TD_ModelSelectionAllButton
                models = app.TD_ModelsListBox.ItemsData;
            else
                models = app.TD_ModelsListBox.Value;
            end

            if isempty(models)
                return;
            end
            
            for i = 1:numel(files)
                % get correct index for data and labels
                [~, file_idx] = ismember(files{i}, app.data_fileNamesTest);

                for j = 1:numel(models)
                    if updateThresholdsOnly
                        if isfield(app.detectionStorage.(files{i}), models{j})
                            app.applyThresholdAndStore(models{j}, ...
                                                       app.detectionStorage.(files{i}).(models{j}).anomalyScores, ...
                                                       app.detectionStorage.(files{i}).(models{j}).timeSeriesTest, ...
                                                       app.detectionStorage.(files{i}).(models{j}).labelsTest, ...
                                                       app.detectionStorage.(files{i}).(models{j}).computationTime, ...
                                                       files{i}, ...
                                                       false);
                        end
                    else                        
                        [anomalyScores, timeSeriesTest, labelsTest, computationTime] = app.Models.(models{j}).detectionWrapper(app.data_dataTest(file_idx), ...
                                                                                                                               app.data_labelsTest(file_idx), ...
                                                                                                                               getComputationTime);
                        app.applyThresholdAndStore(models{j}, anomalyScores, timeSeriesTest, labelsTest, computationTime, files{i}, true);
                    end
                end
            end
        end
        
        function runDetectionOLD(app, models)
            % Main detection function
            % Loops over all models and runs the detection
            
            fprintf("Running detection ...\n");

            app.info_dimensionMismatchDetected = false;

            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No Models (selected).", ...
                        "Detection Error", Icon="error");
                return;
            end

            selectedTestFile = app.TD_TestDataFileDropDown.Value;
            [~, data_idx] = ismember(selectedTestFile, app.data_fileNamesTest);
            testFileVarName = genvarname(selectedTestFile);

            fprintf("Selected file: %s\n", selectedTestFile);

            numModels = numel(models);
            
            for model_idx = 1:numModels
                trainedModel = app.trainedModels.(models(model_idx).id);

                if isfield(trainedModel, "dimensionality")
                    if app.info_numChannels ~= trainedModel.dimensionality
                        uialert(app.TSADPlatformUIFigure, sprintf("%s dimensionality doesn't match the number of channels in the selected test data.", trainedModel.modelOptions.label), ...
                                "Dimensions Error", Icon="error");
                        app.info_dimensionMismatchDetected = true;
                        return;
                    end
                end

                fprintf("Detecting with: %s ...\n", trainedModel.modelOptions.label);

                testingData = app.data_dataTest(data_idx);
                testingLabels = app.data_labelsTest(data_idx);

                % Run detection
                [XTest, timeSeriesTest, labelsTest] = dataTestPreparationWrapper(trainedModel.modelOptions, ...
                                                         testingData, testingLabels);
                
                [anomalyScores, compTime] = detectionWrapper(trainedModel, XTest, timeSeriesTest, labelsTest, ...
                                                             app.TD_GetComputationTimeCheckBox.Value);

                app.updateCompTime(compTime, models(model_idx).label, testFileVarName);
                
                if model_idx == 1
                    % For first model plot results 
                    app.applyThresholdAndStore(trainedModel, anomalyScores, timeSeriesTest, labelsTest, ...
                                        testFileVarName, true, true);
                else
                    % Else just save scores
                    app.applyThresholdAndStore(trainedModel, anomalyScores, timeSeriesTest, labelsTest, ...
                                        testFileVarName, false, true);
                end
            end
            
            app.updateTrainingAndDetectionPanel();
            fprintf("Finished detection successfully!\n");
        end

        function runDetectionForAllFiles(app, models)
            % Loops over all models and files and runs the detection
            
            fprintf("Running detection on all files ...\n");

            app.info_dimensionMismatchDetected = false;

            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No Models (selected).", ...
                        "Detection Error", Icon="error");
                return;
            end

            numTestFiles = numel(app.data_fileNamesTest);
            
            for data_idx = 1:numTestFiles
                currentFileName = app.data_fileNamesTest(data_idx);
                testFileVarName = genvarname(currentFileName);

                fprintf("Detecting on file: %s\n", currentFileName);
    
                numModels = numel(models);
                
                for model_idx = 1:numModels
                    trainedModel = app.trainedModels.(models(model_idx).id);
    
                    if isfield(trainedModel, "dimensionality")
                        if app.info_numChannels ~= trainedModel.dimensionality
                            uialert(app.TSADPlatformUIFigure, sprintf("%s dimensionality doesn't match the number of channels in the selected test data.", trainedModel.modelOptions.label), ...
                                    "Dimensions Error", Icon="error");
                            app.info_dimensionMismatchDetected = true;
                            return;
                        end
                    end
    
                    fprintf("Detecting with: %s ...\n", trainedModel.modelOptions.label);
    
                    testingData = app.data_dataTest(data_idx);
                    testingLabels = app.data_labelsTest(data_idx);
    
                    % Run detection
                    [XTest, timeSeriesTest, labelsTest] = dataTestPreparationWrapper(trainedModel.modelOptions, ...
                                                             testingData, testingLabels);
                    
                    [anomalyScores, compTime] = detectionWrapper(trainedModel, XTest, timeSeriesTest, labelsTest, ...
                                                                 app.TD_GetComputationTimeCheckBox.Value);
    
                    app.updateCompTime(compTime, models(model_idx).label, testFileVarName);
                    
                    if model_idx == 1 && data_idx == 1
                        % For first model and file plot results  
                        app.applyThresholdAndStore(trainedModel, anomalyScores, timeSeriesTest, labelsTest, ...
                                            testFileVarName, true, true);
                        app.TD_TestDataFileDropDown.Value = app.TD_TestDataFileDropDown.Items{data_idx};
                    else
                        % Else just save scores
                        app.applyThresholdAndStore(trainedModel, anomalyScores, timeSeriesTest, labelsTest, ...
                                            testFileVarName, false, true);
                    end
                end
            end
            
            app.updateTrainingAndDetectionPanel();
            fprintf("Finished detection successfully!\n");
        end

        function updateAppliedThreshold(app, models)
            % Applys the selected threshold to the stored anomaly scores
            fprintf("Updating thresholds to %s ...\n", app.TD_ThresholdDropDown.Value);
            
            selectedFileName = app.TD_TestDataFileDropDown.Value;
            testFileVarName = genvarname(selectedFileName);

            fprintf("Selected file: %s\n", selectedFileName);
            
            if ~isfield(app.detectionStorage, testFileVarName)
                return;
            end
            
            % Find models with stored anomaly scores
            updatableModels = [];
            for model_idx = 1:numel(models)
                if isfield(app.detectionStorage, testFileVarName) && ...
                        isfield(app.detectionStorage.(testFileVarName), models(model_idx).id)
                    updatableModels = [updatableModels, models(model_idx)];
                end
            end

            for model_idx = 1:numel(updatableModels)                
                trainedModel = app.trainedModels.(updatableModels(model_idx).id);
                anomalyScores = app.detectionStorage.(testFileVarName).(updatableModels(model_idx).id).anomalyScores;
                timeSeriesTest = app.detectionStorage.(testFileVarName).(updatableModels(model_idx).id).timeSeriesTest;
                labels = app.detectionStorage.(testFileVarName).(updatableModels(model_idx).id).labels;

                if model_idx == 1
                    % Plot detection for first model
                    app.applyThresholdAndStore(trainedModel, anomalyScores, timeSeriesTest, labels, ...
                                        testFileVarName, true, false);
                else
                    app.applyThresholdAndStore(trainedModel, anomalyScores, timeSeriesTest, labels, ...
                                        testFileVarName, false, false);
                end
            end

            app.updateTrainingAndDetectionPanel();
            fprintf("Finished updating thresholds successfully!\n");
        end

        function updateAppliedThresholdForAllFiles(app, models)
            % Applys the selected threshold to the stored anomaly scores
            % for all test files
            fprintf("Updating thresholds for all files to %s ...\n", app.TD_ThresholdDropDown.Value);

            numTestFiles = numel(app.data_fileNamesTest);
            
            updatableFileNames = [];
            updatableFileVarNames = [];
            firstUpdatableFile_idx = [];
            
            for data_idx = 1:numTestFiles
                currentFileName = app.data_fileNamesTest(data_idx);
                testFileVarName = genvarname(currentFileName);
                if isfield(app.detectionStorage, testFileVarName)
                    for model_idx = 1:numel(models)
                        if isfield(app.detectionStorage.(testFileVarName), models(model_idx).id)
                            updatableFileNames = [updatableFileNames, currentFileName];
                            updatableFileVarNames = [updatableFileVarNames, string(testFileVarName)];
                            if isempty(firstUpdatableFile_idx)
                                firstUpdatableFile_idx = data_idx;
                            end
                            break;
                        end
                    end
                end
            end
            
            numUpdatableFiles = numel(updatableFileVarNames);
            for data_idx = 1:numUpdatableFiles
                testFileVarName = updatableFileVarNames(data_idx);
                fprintf("Updating thresholds for file: %s\n", updatableFileNames(data_idx));

                updatableModels = [];
                for model_idx = 1:numel(models)
                    if isfield(app.detectionStorage.(testFileVarName), models(model_idx).id)
                        updatableModels = [updatableModels, models(model_idx)];
                    end
                end

                for model_idx = 1:numel(updatableModels)                
                    trainedModel = app.trainedModels.(updatableModels(model_idx).id);
                    anomalyScores = app.detectionStorage.(testFileVarName).(updatableModels(model_idx).id).anomalyScores;
                    timeSeriesTest = app.detectionStorage.(testFileVarName).(updatableModels(model_idx).id).timeSeriesTest;
                    labels = app.detectionStorage.(testFileVarName).(updatableModels(model_idx).id).labels;
    
                    if data_idx == 1 && model_idx == 1
                        % Plot detection for first model and file
                        app.applyThresholdAndStore(trainedModel, anomalyScores, timeSeriesTest, labels, ...
                                            testFileVarName, true, false);
                        app.TD_TestDataFileDropDown.Value = app.TD_TestDataFileDropDown.Items{firstUpdatableFile_idx};
                    else
                        % Just update scores
                        app.applyThresholdAndStore(trainedModel, anomalyScores, timeSeriesTest, labels, ...
                                            testFileVarName, false, false);
                    end
                end
            end

            app.updateTrainingAndDetectionPanel();
            fprintf("Finished updating thresholds successfully!\n");
        end

        function applyThresholdAndStore(app, modelID, anomalyScores, timeSeries, labels, computationTime, fileName, isInitialDetection)
            %APPLYTHRESHOLDANDSTORE Updates the detection by applying the
            %slected threshold and storing the scores
            
            % Apply threshold if required
            if strcmp(app.Models.(modelID).parameters.outputType, "anomaly_scores")
                selectedThreshold = app.TD_ThresholdDropDown.Value;

                % Check if threshold in storage to avoid re-calculating the same threshold
                if ~isInitialDetection ...
                        && isfield(app.detectionStorage.(fileName), modelID) ...
                        && isfield(app.detectionStorage.(fileName).(modelID), "thresholds") ...
                        && isfield(app.detectionStorage.(fileName).(modelID).thresholds, selectedThreshold)
                    storedThreshold = app.detectionStorage.(fileName).(modelID).thresholds.(selectedThreshold);
                    storedThresholdFound = true;
                else
                    storedThreshold = [];
                    storedThresholdFound = false;
                end
                
                % Apply threshold. If storedThreshold is empty the
                % threshold will be computed inside this function or taken
                % from the model itself
                [predictedLabels, threshold] = app.Models.(modelID).applyThreshold(anomalyScores, labels, selectedThreshold, app.settings_dynamicThreshold, storedThreshold);
            else
                predictedLabels = anomalyScores;
                threshold = [];
            end
            
            % Store scores
            scores = computeMetrics(anomalyScores, predictedLabels, labels);
            scores = [computationTime; scores];
            app.updateFullScores(scores, app.Models.(modelID).instanceInfo.label, fileName);
            
            % Save anomaly scores, time series and labels for new detections and reset stored thresholds
            if isInitialDetection
                app.detectionStorage.(fileName).(modelID).timeSeriesTest = timeSeries;
                app.detectionStorage.(fileName).(modelID).anomalyScores = anomalyScores;
                app.detectionStorage.(fileName).(modelID).labels = labels;

                if strcmp(app.Models.(modelID).parameters.outputType, "anomaly_scores")
                    app.removeStoredThresholdsOf(modelID, fileName);
                end
            end
            
            % Store current threshold and predicted Labels
            app.detectionStorage.(fileName).(modelID).threshold = threshold;
            app.detectionStorage.(fileName).(modelID).predictedLabels = predictedLabels;
            
            % Save threshold if not already stored
            if strcmp(app.Models.(modelID).parameters.outputType, "anomaly_scores") && ~storedThresholdFound
                app.addToThresholdStorage(selectedThreshold, threshold, modelID, fileName);
            end
        end

        function updateDetectionPlots(app)
            %UPDATEDETECTIONPLOTS Updates plots on training and detection
            %panel showning to show the detection for the correct model, file and threshold

            % If currentDetection_modelID is empty, select top ranking model
            if isempty(app.currentDetection_modelID)
                if isempty(app.TD_ModelsListBox.ItemsData)
                    return;
                end

                app.currentDetection_modelID = app.TD_ModelsListBox.ItemsData{1};
            end
            
            % Clear plots first and later fill with new conent
            app.clearDetectionPlots();

            fileName = app.TD_TestDataFileDropDown.Value;

            if ~isfield(app.detectionStorage, fileName)
                return;
            end

            if ~isfield(app.detectionStorage.(fileName), app.currentDetection_modelID)
                return;
            end

            label = app.Models.(app.currentDetection_modelID).instanceInfo.label;
            app.TD_DetectionShownForLabel.Text = sprintf("Detection shown for %s", label);
            
            % Get time series, anomaly scores and labels for plotting
            timeSeriesTest = app.detectionStorage.(fileName).(app.currentDetection_modelID).timeSeriesTest;
            anomalyScores = app.detectionStorage.(fileName).(app.currentDetection_modelID).anomalyScores;
            labels = app.detectionStorage.(fileName).(app.currentDetection_modelID).labels;
            scores = app.fullScores.(fileName).(label);
            predictedLabels = app.detectionStorage.(fileName).(app.currentDetection_modelID).predictedLabels;
            threshold = app.detectionStorage.(fileName).(app.currentDetection_modelID).threshold;

            % Plot time series
            if iscell(timeSeriesTest)
                timeSeriesTest = cell2mat(timeSeriesTest);
            end
            plot(app.TD_PlotsDataUIAxes, timeSeriesTest(:, 1));

            % Plot anomaly scores / binary detection for models which
            % output labels
            if strcmp(app.Models.(app.currentDetection_modelID).parameters.outputType, "anomaly_scores")
                app.TD_PlotsAnomalyScoresUIAxes.Title.String  = "Anomaly Scores";
                plot(app.TD_PlotsAnomalyScoresUIAxes, anomalyScores);
            else
                app.TD_PlotsAnomalyScoresUIAxes.Title.String  = "Predicted Labels";
                plot(app.TD_PlotsAnomalyScoresUIAxes, predictedLabels);
            end
            
            % Plot transparent boxes for true and found anomalies
            predictedIndices = getAnomalyIndices(predictedLabels);
            trueIndices = getAnomalyIndices(labels);
            
            % Get limits
            YLims = get(app.TD_PlotsDataUIAxes, "YLim");
            predictedLims = [];
            trueLims = [];
            for i = 1:size(predictedIndices, 2)
                predictedLims(1, i) = YLims(1);
                predictedLims(2, i) = YLims(1);
                predictedLims(3, i) = YLims(2);
                predictedLims(4, i) = YLims(2);
            end
            for i = 1:size(trueIndices, 2)
                trueLims(1, i) = YLims(1);
                trueLims(2, i) = YLims(1);
                trueLims(3, i) = YLims(2);
                trueLims(4, i) = YLims(2);
            end
            
            if ~isempty(predictedIndices) && ~isempty(predictedLims)
                patch(app.TD_PlotsDataUIAxes, predictedIndices, predictedLims, "red", EdgeColor="none", FaceAlpha=0.4);
            end
            if ~isempty(trueIndices) && ~isempty(trueLims)
                patch(app.TD_PlotsDataUIAxes, trueIndices, trueLims, "green", EdgeColor="none", FaceAlpha=0.3);
            end

            % Plot threshold
            if strcmp(app.Models.(app.currentDetection_modelID).parameters.outputType, "anomaly_scores")
                if numel(threshold) > 1
                    windowSize = max(1, floor(size(anomalyScores, 1) * (app.settings_dynamicThreshold.windowSize / 100)));
                    threshX = [];
                    threshY = [];
                    for i = 1:numel(threshold)
                        threshX(i, :) = ((i - 1) * windowSize + 1):(windowSize * i);
                        threshY(i, :) = ones(1, windowSize) * threshold(i);
                    end

                    hold(app.TD_PlotsAnomalyScoresUIAxes, "on")
                    plot(app.TD_PlotsAnomalyScoresUIAxes, threshX', threshY', Color="red")
                    hold(app.TD_PlotsAnomalyScoresUIAxes, "off")
                else
                    yline(app.TD_PlotsAnomalyScoresUIAxes, threshold, "r");
                end
            end
            
            % Limits
            set(app.TD_PlotsAnomalyScoresUIAxes, "XLim", [0 size(anomalyScores, 1)]);
            set(app.TD_PlotsDataUIAxes, "XLim", [0 size(anomalyScores, 1)]);
%             set(app.TD_PlotsAnomalyScoresUIAxes, "YLim", [min(anomalyScores) max(anomalyScores)]);
%             set(app.TD_PlotsDataUIAxes, "YLim", [min(timeSeriesTest(:, 1)) max(timeSeriesTest(:, 1))]);
            
            % Legends
            try
                legend(app.TD_PlotsDataUIAxes, "Data", "Detected Anomalies", "True Anomalies")
            catch
                legend(app.TD_PlotsDataUIAxes, "Data", "Detected Anomalies")
            end            
            
            % Table of scores
            app.TD_PlotsScoresUITable.Data = table(METRIC_NAMES_WITH_COMP_TIME, scores);
        end

        function clearDetectionPlots(app)
            %CLEARDETECTIONPLOTS Clears plots on training and detection
            %panel

            app.TD_DetectionShownForLabel.Text = "Detection Shown for: None";
            app.TD_PlotsScoresUITable.Data = [];
            cla(app.TD_PlotsDataUIAxes);
            cla(app.TD_PlotsAnomalyScoresUIAxes);
            app.currentDetection_modelID = [];
        end
        
        function updateTinyDataPreview(app)
            %UPDATETINYDATAPREVIEW Updates the data plot on the training
            %and detection panel

            selectedFile = app.TD_TestDataFileDropDown.Value;
            [~, i] = ismember(selectedFile, app.TD_TestDataFileDropDown.Items);
            plot(app.TD_FileSelectionTinyDataPreviewUIAxes, app.data_dataTest{i});
        end
        
        function updateStorage(app)
            %UPDATESTORAGE Updates all detection storage variables. This is
            %the only function that must be called when for example the
            %dataset changes or a model is deleted/added.
            
            if ~app.info_dataLoaded
                return;
            end
            
            oldFiles = fieldnames(app.fullScores);
            currentFiles = app.data_fileNamesTest;
            
            oldModels = app.averageScores.Properties.VariableNames;
            if numel(oldModels) < 2
                oldModels = [];
            else
                oldModels = oldModels(2:end);
            end
            currentModelIDs = fieldnames(app.Models);
            currentModels = strings(numel(currentModelIDs), 1);
            for i = 1:numel(currentModelIDs)
                currentModels(i) = app.Models.(currentModelIDs{i}).instanceInfo.label;
            end

            % Remove non-relevant files (if any) from old scores, anomalyscore, thresholds
            for i = 1:numel(oldFiles)
                if ~ismember(oldFiles{i}, currentFiles)
                    app.fullScores = rmfield(app.fullScores, oldFiles{i});
                    app.detectionStorage = rmfield(app.detectionStorage, oldFiles{i});
                end
            end

            % Remove deleted models (if any) from scores and detection
            % storage
            for i = 1:numel(oldModels)
                if ~ismember(oldModels(i), currentModels)
                    app.removeModelFromDetectionStorage(currentModelIDs{i});
                    app.removeModelFromStorage(currentModels(i));
                end
            end
            
            % Init new files in scores and detection storage
            table_tmp = table(METRIC_NAMES_WITH_COMP_TIME);
            for i = 1:numel(currentFiles)
                if ~isfield(app.fullScores, currentFiles{i})
                    app.fullScores.(currentFiles{i}) = table_tmp;
                    app.fullScores.(currentFiles{i}).Properties.VariableNames = "Metric";
                end

                if ~isfield(app.detectionStorage, currentFiles{i})
                    app.detectionStorage.(currentFiles{i}) = struct();
                end
            end

            % Add models to scores
            for i = 1:numel(currentModelIDs)
                app.addModelToStorage(currentModelIDs{i});
            end
        end

        function updateCompTimePlot(app)
            %UPDATECOMPTIMEPLOT Updates computation time plot

            if ~app.info_testDataLoaded
                return;
            end

            cla(app.TD_CompTimeUIAxes);
            if isempty(app.compTimeMetrics)
                xticks(app.TD_CompTimeUIAxes, []);
                return;
            end

            shownScores = app.fullScores.(app.TD_TestDataFileDropDown.Value);

            metrics_idx = [];
            for i = 1:numel(app.compTimeMetrics)                
                metrics_idx(i) = find(shownScores{:, 1} == string(app.compTimeMetrics(i)));
            end

            elapsed = shownScores{1, 2:end};
            elapsed_idx = 2:(size(shownScores, 2));
            nan_idx = isnan(elapsed);
            elapsed_idx(nan_idx) = [];
            elapsed(nan_idx) = [];

            [elapsed, sort_idx] = sort(elapsed);
            elapsed_idx = elapsed_idx(sort_idx);

            if numel(elapsed) > 1
                for i = 1:numel(elapsed) - 1
                    if elapsed(i + 1) == elapsed(i)
                        elapsed(i + 1) = elapsed(i + 1) + 0.0001;
                    end
                end
            end

            cla(app.TD_CompTimeUIAxes)

            if ~isempty(app.compTimeMetrics)
                hold(app.TD_CompTimeUIAxes, "on");
                for i = 1:numel(app.compTimeMetrics)
                    plot(app.TD_CompTimeUIAxes, elapsed, shownScores{metrics_idx(i), elapsed_idx}, ".", MarkerSize=5);
                end
                legend(app.TD_CompTimeUIAxes, app.compTimeMetrics, Location="northwest");                
                xticks(app.TD_CompTimeUIAxes, elapsed);
                xticklabels(app.TD_CompTimeUIAxes, shownScores.Properties.VariableNames(elapsed_idx));
            end
        end

        function addToThresholdStorage(app, thresholdName, thresholdValue, modelID, fileName)
            %ADDTOTHRESHOLDSTORAGE Stores already computed thresholds
            
            if ~strcmp(thresholdName, "dynamic") % Don't save dynamic threshold because it can be reconfigured.
                app.detectionStorage.(fileName).(modelID).thresholds.(thresholdName) = thresholdValue;
            end
        end

        function updateFullScores(app, scores, modelLabel, fileName)
            %UPDATEFULLSCORES Updates the scores saved in the full scores

            app.fullScores.(fileName).(modelLabel) = scores;
        end

        function updateAverageScores(app)
            %UPDATEAVERAGESCORES Updates average scores saved in averageScores table
            
            if isempty(app.data_fileNamesTest)
                return;
            end

            fullScoresCell = cell(numel(app.data_fileNamesTest), 1);
            for i = 1:numel(app.data_fileNamesTest)
                fullScoresCell{i} = table2array(app.fullScores.(app.data_fileNamesTest(i))(:, 2:end));
            end
            
            % Copy full scores to average scores to init layout of table
            app.averageScores = app.fullScores.(app.data_fileNamesTest(1));

            % Now fill with average scores
            app.averageScores{:, 2:end} = calcAverageScores(fullScoresCell); 
        end

        function updateCompTime(app, compTime, modelLabel, fileName)
            %UPDATECOMPTIME Updates the computation time in the full scores

            scores = app.fullScores.(fileName).(modelLabel)(2:end);
            app.fullScores.(fileName).(modelLabel) = [compTime; scores];
        end

                
        function clearStorage(app)
            %CLEARSTORAGE Removes all stored scores, anomaly scores and thresholds
            
            tableTmp = table(METRIC_NAMES_WITH_COMP_TIME);
            for i = 1:numel(app.data_fileNamesTest)
                % Replace table of scores with default table without models
                app.fullScores.(app.data_fileNamesTest(i)) = tableTmp;
                app.fullScores.(app.data_fileNamesTest(i)).Properties.VariableNames = "Metric";

                % Replace detection storage with empty struct
                app.detectionStorage.(app.data_fileNamesTest(i)) = struct();
            end
        end

        function addModelToStorage(app, modelID)
            %ADDMODELTOSTORAGE Adds a new model to the scores and detection
            %storage

            modelLabel = app.Models.(modelID).instanceInfo.label;
            
            if isempty(app.data_fileNamesTest)
                return;
            end
            
            for i = 1:numel(app.data_fileNamesTest)
                if ~ismember(modelLabel, app.fullScores.(app.data_fileNamesTest(i)).Properties.VariableNames)
                    app.fullScores.(app.data_fileNamesTest(i)).(modelLabel) = [NaN; NaN; NaN; NaN; ...
                                                                               NaN; NaN; NaN; NaN; ...
                                                                               NaN; NaN; NaN; NaN; ...
                                                                               NaN; NaN; NaN; NaN];

                end

                if ~isfield(modelID, app.detectionStorage)
                    app.detectionStorage.(modelID) = struct();
                end
            end
            
        end

        function removeModelFromStorage(app, modelID)
            %REMOVEMODELFROMSTORAGE Removes a model to the scores and detection
            %storage
            
            modelLabel = app.Models.(modelID).instanceInfo.label;

            if isempty(app.data_fileNamesTest)
                return;
            end

            for i = 1:numel(app.data_fileNamesTest)
                if ismember(modelLabel, app.fullScores.(app.data_fileNamesTest(i)).Properties.VariableNames)
                    app.fullScores.(app.data_fileNamesTest(i)) = removevars(app.fullScores.(app.data_fileNamesTest(i)), modelLabel);
                end

                if isfield(app.detectionStorage.(app.data_fileNamesTest(i)), modelID)
                    app.detectionStorage.(app.data_fileNamesTest(i)) = rmfield(app.detectionStorage.(app.data_fileNamesTest(i)), modelID);
                end
            end
        end

        function removeStoredThresholdsOf(app, modelID, fileName)
            %REMOVESTOREDTHRESHOLDSOF Removes stored thresholds for model and file

            if isfield(app.detectionStorage, fileName) ...
                    && isfield(app.detectionStorage.(fileName), modelID) ...
                    && isfield(app.detectionStorage.(fileName).(modelID), "thresholds") 
                app.detectionStorage.(fileName).(modelID) = rmfield(app.detectionStorage.(fileName).(modelID), "thresholds");
            end
        end
        
        function removePreviousDetectionsOf(app, modelID)
            %REMOVEPREVIOUSDETECTIONSOF Removes all previous detections 
            % of a model required if model is trained multiple times
            
            app.removeModelFromStorage(modelID);

            if strcmp(app.currentDetection_modelID, modelID)
                app.clearDetectionPlots();
            end
        end

        function displayModels(app)
            %DISPLAYMODELS Sorts and displays the models on the detection panels
            % The sorting is done according to the average score (as selected by the sort-by dropdown) of the
            % model

            app.TD_ModelsListBox.Items = {};
            app.TD_ModelsListBox.ItemsData = [];

            % Get all model IDs
            modelIDs = fieldnames(app.Models);
            numModels = numel(modelIDs);

            if numModels == 0
                return;
            end
            
            % If no scores are available yet or no test data is loaded
            if isempty(app.averageScores)
                items = strings(numModels, 1);
                itemsData = cell(numModels, 1);
    
                for i = 1:numModels
                    modelID= modelIDs{i};
                    modelLabel = app.Models.(modelID).instanceInfo.label;

                    items(i) = modelLabel;
                    itemsData{i} = modelID;
                end

                app.TD_ModelsListBox.Items = items;
                app.TD_ModelsListBox.ItemsData = itemsData;
                return;
            end


            % Metric for sorting models
            sortBy = app.TD_SortByDropDown.Value;
            [~, scoreIdx] = ismember(sortBy, app.averageScores{:, 1});

            items = strings(numModels, 1);
            itemsData = cell(numModels, 1);
            scoreValues = zeros(numModels, 1);

            for i = 1:numModels
                modelID= modelIDs{i};
                modelLabel = app.Models.(modelID).instanceInfo.label;
                
                % Get scores for model
                modelScores = app.averageScores.(modelLabel);

                scoreValues(i) = modelScores(scoreIdx);
                % if isnan(scoreValues(i))
                %     scoreValues(i) = 0;
                % end

                items(i) = modelLabel;
                itemsData{i} = modelID;
            end
            
            % Sort models
            [~, sortedScoreIdx] = sort(scoreValues, "descend");

            sortedItems = items(sortedScoreIdx);
            sortedItemsData = itemsData(sortedScoreIdx);
            
            % Add model rank and score to label
            for i = 1:numel(sortedItems)
                if ~isnan(scoreValues(i))
                    sortedItems(i) = sprintf('%d. (Score:%.2f)   %s', i, scoreValues(i), sortedItems(i));
                end
            end

            app.TD_ModelsListBox.Items = sortedItems;
            app.TD_ModelsListBox.ItemsData = sortedItemsData;
        end

        function displayScores(app)
            %DISPLAYSCORES Shows scores for current data file and trained models
            
            if ~app.info_testDataLoaded
                return;
            end

            shownScores = app.fullScores.(app.TD_TestDataFileDropDown.Value);
            app.TD_ScoresUITable.Data = {};
            app.TD_AverageScoresUITable.Data = {};
            if ~isempty(shownScores)
                app.TD_ScoresUITable.Data = shownScores;
                app.TD_AverageScoresUITable.Data = app.averageScores;
                app.TD_ScoresUITable.ColumnName = shownScores.Properties.VariableNames;
                app.TD_AverageScoresUITable.ColumnName = app.averageScores.Properties.VariableNames;
                
                if size(shownScores, 2) > 1
                    removeStyle(app.TD_ScoresUITable);
                    removeStyle(app.TD_AverageScoresUITable);

                    % Add blue background for comp time row
                    styleBlue = uistyle;
                    styleBlue.BackgroundColor = "#97b0e7";
                    addStyle(app.TD_ScoresUITable, styleBlue, row=1);
                    addStyle(app.TD_AverageScoresUITable, styleBlue, row=1);

                    % Highlight maxima
                    app.highlightTableMaxima(app.TD_ScoresUITable, "all", 1);
                    app.highlightTableMaxima(app.TD_AverageScoresUITable, "all", 1);
                end
            end
        end
     
        % function path = saveModelForSim(app, Mdl, modelID)
        %     % Saves the selected model for the simulink detection
        % 
        %     f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
        %     [file, path] = uiputfile("*.mat", "Save Model for Simulink Detection", sprintf("%s.mat", modelID));
        %     delete(f);
        %     if isequal(path, 0)
        %         uialert(app.TSADPlatformUIFigure, "No valid path selected.", ...
        %                 "Export Error", Icon="error");
        %         return;
        %     end
        % 
        %     fileName = fullfile(path, file);
        %     assignin("base", "Mdl", Mdl);
        %     save(fileName, "Mdl");
        % 
        %     path = fileName;
        % end

        function saveScoresTable(app, UITable, dlgTitle, fileName)
            table = UITable.Data;
            table.Properties.VariableNames = UITable.ColumnName;

            variableNames = table.Properties.VariableNames;
            if numel(variableNames) < 2
                return;
            end


            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            [file, path] = uiputfile("*.csv", dlgTitle, sprintf("%s_%s.csv", fileName, datetime("now", 'Format','MMMM_d_yyyy_HH_mm_ss')));
            delete(f);
            if isequal(path, 0)
                uialert(app.TSADPlatformUIFigure, "No valid path selected.", ...
                        "Export Error", Icon="error");
                return;
            end

            exportFileName = fullfile(path, file);
            
            try
                writetable(table, exportFileName);
            catch ME
                uialert(app.TSADPlatformUIFigure, ME.message, "Export Error", Icon="error");
                return;
            end

            uialert(app.TSADPlatformUIFigure, sprintf("Saved table to %s", exportFileName), ...
                    "Table Saved Successfully", Icon="success");
        end

        function loadModelConfigFile(app)
            %LOADMODELCONFIGFILE Loads models from configuration file

            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            [file, path] = uigetfile("*.json", "Select Config File", pwd);
            delete(f);
            
            if ~isequal(file, 0) && ~isequal(path, 0)
                filePath = fullfile(path, file);
                fid = fopen(filePath);
                raw = fread(fid, inf);
                str = char(raw');
                fclose(fid);

                JSONData = jsondecode(str);
                
                for i = 1:numel(JSONData.models)
                    model = JSONData.models(i);
                    app.addModel(model.parameters);
                end                
            end
        end

        function loadModelConfigDefault(app)
            %LOADMODELCONFIGDEFAULT Loads default model config from file

            fileName = "TSADConfig_models.json";
            fid = fopen(fileName);
            raw = fread(fid, inf);
            str = char(raw');
            fclose(fid);

            JSONData = jsondecode(str);

            models = fieldnames(JSONData);
            
            for i = 1:numel(models)
                model = JSONData.(models{i});
                parameters = struct();

                if isfield(model.parameters, "static")
                    fields = fieldnames(model.parameters.static);
                    for j = 1:numel(fields)
                        parameters.(fields{j}) = model.parameters.static.(fields{j});
                    end
                end

                if isfield(model.parameters, "configurable")
                    fields = fieldnames(model.parameters.configurable);
                    for j = 1:numel(fields)
                        parameters.(fields{j}) = model.parameters.configurable.(fields{j}).initialValue;
                    end
                end

                app.addModel(parameters);
            end                
        end

        function exportModelConfig(app)
            %EXPORTMODELCONFIG Exports configs of models

            modelIDs = fieldnames(app.Models);

            JSONData = struct();
            JSONData.models = [];

            for i = 1:numel(modelIDs)
                model.parameters = app.Models.(modelIDs{i}).parameters;
                JSONData.models = [JSONData.models, model];
            end

            app.exportStructToJSON(JSONData);
        end

        function exportStructToJSON(app, structData)
            %EXPORTSTRUCTTOJSON Exports a struct to a JSON file

            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            [file, path] = uiputfile("*.json", "Select Folder", "myConfig.json");
            delete(f);
            if isequal(path, 0)
                uialert(app.TSADPlatformUIFigure, "No valid path selected.", ...
                        "Export Error", Icon="error");
                return;
            end

            fileName = fullfile(path, file);
            fid = fopen(fileName, "w+");
            encodedJSON = jsonencode(structData, PrettyPrint=true);
            fprintf(fid, encodedJSON);
        end

        function status = exportModels(app)
            %EXPORTMODELS Exports models (not only config but entire TSAD
            %model struct)

            if isempty(fieldnames(app.Models))
                status = "no models found";
                return;
            end

            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            [file, path] = uiputfile("*.mat", "Export Models", "models.mat");
            delete(f);

            if isequal(path, 0)
                status = "invalid path selected";
                return;
            end
            
            Models = app.Models;
            
            exportFileName = fullfile(path, file);
            assignin("base", "Models", Models);
            save(exportFileName, "Models");

            status = "success";
        end
        
        function errorOccured = importModels(app)
            %IMPORTMODELS Import models (not only config but entire TSAD
            %model struct)

            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            [file, path] = uigetfile("*.mat", "Select a trained Models File", pwd);
            delete(f);

            if ~isequal(file, 0) && ~isequal(path, 0)
                filePath = fullfile(path, file);
                
                try
                    app.Models = load(filePath).Models;
                catch
                    errorOccured = true;
                    return;
                end
                
                app.initNewTrainedModels();    
                app.updateTrainingAndDetectionPanel();
            end

            errorOccured = false;
        end


        function updateTrainingAndDetectionPanel(app)
            %UPDATETRAININGANDDETECTIONPANEL Update listbox items
            app.updateAverageScores();
            app.displayScores();
            app.displayModels();
            app.updateDetectionPlots();
            app.updateCompTimePlot();
        end
    end

    methods (Access = public)
%% Settings update functions called by settings popup apps
        
        function bestModels = findBestModels(app, metric)
            [~, metric_idx] = ismember(metric, METRIC_NAMES);
            metric_idx = metric_idx + 1; % Add 1 because fullScores containes the comp time in the first row

            testFiles = fieldnames(app.fullScores);

            for data_idx = 1:numel(testFiles)
                fileScores = app.fullScores.(testFiles{data_idx});
                [~, model_idx] = max(fileScores{metric_idx, 2:end});
                modelLabel = fileScores.Properties.VariableNames(model_idx + 1);
                for i = 1:numel(app.TD_ModelsListBox.ItemsData)
                    if strcmp(app.TD_ModelsListBox.ItemsData(i).label, modelLabel)
                        modelID = app.TD_ModelsListBox.ItemsData(i).id;
                        break;
                    end
                end
                bestModels.(testFiles{data_idx}).id = modelID;
                bestModels.(testFiles{data_idx}).label = modelLabel;
            end
        end
        

%% Training and Detection panel functions called by popup apps
        
        function addModel(app, parameters)
            %ADDMODEL Adds configured model to platform

            name = parameters.name;
            className = parameters.className;
            
            % Construct instanceInfo
            instanceNumber = app.getModelInstanceNumber(name);
            instanceInfo.instanceNumber = instanceNumber;
            
            instanceInfo.label = sprintf("%s (%d)", name, instanceNumber);
            instanceInfo.id = sprintf("%s_%d", className, instanceNumber);
            
            % Call constructor of class for model in "models" package (See
            % folder "src/models/+models")
            app.Models.(instanceInfo.id) = models.(className)(parameters, instanceInfo);

            % Update panel
            app.updateStorage();
            app.updateTrainingAndDetectionPanel();
        end

        function updateModel(app, modelID, parameters)
            %UPDATEMODEL Updates model parameters

            if ~isfield(app.Models, modelID)
                return;
            end
            
            % Update parameters
            app.Models.(modelID) = app.Models.(modelID).setParameters(parameters);

            % Reset storage and update panel
            app.removeModelFromStorage(modelID);
            app.updateStorage();
            app.updateTrainingAndDetectionPanel();
        end

        function closeProgressDialog(app)
            %CLOSEPROGRESSDIALOG Closes progressdialog
            close(app.progressDialog);
        end

        function optimizeModels(app, modelIDs, iterations, metric)
            %OPTIMIZEMODELS Optimizes the models using bayesian
            %optimization

            delete(app.popup_OptimizationConfigurator);

            % Check if optimization can be done (required data must be
            % loaded)
            if ~app.info_testDataLoaded
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, "No testing data loaded!", ...
                        "Optimization Error", Icon="error");
                return;
            end

            if isempty(modelIDs)
                close(app.progressDialog);
                uialert(app.TSADPlatformUIFigure, "No models found for optimization.", ...
                        "Model Error", Icon="error");
                return;
            end

            % If no training data is loaded but a model requiring prior
            % training was selected
            if isempty(app.data_dataTrain)
                for i = 1:numel(modelIDs)
                    if ~strcmp(app.Models.(modelIDs(i)).modelInfo.learningType, "unsupervised")
                        close(app.progressDialog);
                        uialert(app.TSADPlatformUIFigure, "You are trying to optimize a model but the selected dataset doesn't contain training data", ...
                                "Optimization Error", Icon="error");
                        return;
                    end
                end
            end

            if app.TD_TrainingPlotsCheckBox.Value
                trainingPlots = "training-progress";
            else
                trainingPlots = "none";
            end

            for i = 1:numel(modelIDs)
                % Call optimization wrapper function on model object
                app.Models.(modelIDs{i}) = app.Models.(modelIDs{i}).optimizationWrapper(app.data_dataTrain, ...
                                                                                        app.data_labelsTrain, ...
                                                                                        app.data_dataTestVal, ...
                                                                                        app.data_labelsTestVal, ...
                                                                                        app.data_dataTest, ...
                                                                                        app.data_labelsTest, ...
                                                                                        metric, ...                                                                                    
                                                                                        app.TD_ThresholdDropDown.Value, ...
                                                                                        app.settings_dynamicThreshold, ...
                                                                                        iterations, ...
                                                                                        trainingPlots, ...
                                                                                        app.settings_parallelEnabled);


                % Remove previous detections of models from storage. This
                % does nothing if the model is trained for the first time.
                app.removePreviousDetectionsOf(modelIDs{i});
            end
           
            app.updateStorage();
            app.updateTrainingAndDetectionPanel();


            close(app.progressDialog);
            uialert(app.TSADPlatformUIFigure, "Successfully finished optimization.", ...
                    "Optimization Successful", Icon="success");
        end
        
        function updateScoringFunctionForSelection(app, scoringFunction)
            % Updates the scoring function for the selected models (only if
            % they have the scoringFuncion hyperparameter)
            
            % Delete pupup window
            app.popup_ScoringFunctionSelection.delete();

            models = app.T_ModelsListBox.Value;
   
            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No models selected.", ...
                        "Model Error", Icon="error");
                return;
            end


            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Updating Scoring Functions ...", ...
                              Indeterminate="on");
            drawnow;

            for model_idx = 1:numel(models)
                modelOptions_tmp = models(model_idx).modelOptions;
               
                % Convert modelOptions on training panel
                if isfield(modelOptions_tmp, "hyperparameters") && ...
                    isfield(modelOptions_tmp.hyperparameters, "scoringFunction")
                    modelOptions_tmp.hyperparameters.scoringFunction = scoringFunction;
                    app.convertModelOptionsInList(modelOptions_tmp);


                    % Update trained model
                    if isfield(app.trainedModels, modelOptions_tmp.id)
                        trainedModel_tmp = app.trainedModels.(modelOptions_tmp.id);
                        trainedModel_tmp.modelOptions = modelOptions_tmp;
                        updatedTrainedModels.(modelOptions_tmp.id) = trainedModel_tmp;
                    end
                end
            end

            if exist("updatedTrainedModels", "var")
                app.initNewTrainedModels(updatedTrainedModels);
                app.updateTrainingAndDetectionPanel();
            end

            close(app.progressDialog);
            uialert(app.TSADPlatformUIFigure, "Successfully updated scoring functions.", ...
                    "Update Successful", Icon="success");
        end
        
%% Detection panel functions calles by popup apps
        
        function updateCompTimeMetrics(app, newMetrics)
            %UPDATECOMPTIMEMETRICS Updates the computation time metric selection

            app.compTimeMetrics = newMetrics;

            app.updateCompTimePlot();
        end
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Turn off axes for some UIAxes
            app.disableAxesFor([app.T_PreviewUIAxes, app.TD_FileSelectionTinyDataPreviewUIAxes]);            
            
            % Init sort by dropdown
            app.initSortbyDropdown();
            
            % Init threshold dropdown items
            app.initThresholdDropdowns();
            
            % Init dynamic threshold settings
            app.initDynamicThresholdSettings();
        end

        % Close request function: TSADPlatformUIFigure
        function TSADPlatformUIFigureCloseRequest(app, event)
            answer = uiconfirm(app.TSADPlatformUIFigure, "Close TSAD platform?", "Confirm Close", ...
                Options={'Ok', 'Cancel'}, DefaultOption='Cancel', CancelOption='Cancel');

            if ~strcmp(answer, 'Ok')
                return;
            end
            
            if ~isempty(app.popup_ModelSelection)
                app.popup_ModelSelection.delete()
            end
            if ~isempty(app.popup_ModelParametersViewAndUpdate)
                app.popup_ModelParametersViewAndUpdate.delete()
            end
            if ~isempty(app.popup_OptimizationConfigurator)
                app.popup_OptimizationConfigurator.delete()
            end
            if ~isempty(app.popup_ThresholdSelection)
                app.popup_ThresholdSelection.delete()
            end
            if ~isempty(app.popup_CompTimeMetricSelection)
                app.popup_CompTimeMetricSelection.delete()
            end
            if ~isempty(app.popup_DatasetVisualization)
                app.popup_DatasetVisualization.delete()
            end
            if ~isempty(gcp("nocreate"))
                delete(gcp("nocreate"));
            end
            
            app.delete()
        end

        % Callback function
        function DynamicThresholdMenuSelected(app, event)
            app.popup_DynamicThresholdSettings = DynamicThresholdSettings(app, app.settings_dynamicThreshold);
        end

        % Menu selected function: EnableParallelModeMenu
        function EnableParallelModeMenuSelected(app, event)
            if strcmp(app.EnableParallelModeMenu.Text, "Enable Parallel Mode")
                app.EnableParallelModeMenu.Text = "Disable Parallel Mode";
                app.settings_parallelEnabled = true;
            else
                app.EnableParallelModeMenu.Text = "Enable Parallel Mode";
                app.settings_parallelEnabled = false;
            end

            if app.settings_parallelEnabled
                uialert(app.TSADPlatformUIFigure, "Enabled parallel mode. Training will now be run in parallel.", ...
                        "Switched Mode Successfully", Icon="success");
            else
                uialert(app.TSADPlatformUIFigure, "Disabled parallel mode. Training will now be run consecutively.", ...
                        "Switched Mode Successfully", Icon="success");
            end
        end

        % Button pushed function: DP_BrowseDatasetButton
        function DP_BrowseDatasetButtonPushed(app, event)
            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            path = uigetdir(pwd);
            delete(f);
            if ~isequal(path, 0)
                app.DP_DatasetFolderEditField.Value = path;
            end
        end

        % Button pushed function: DP_LoadDataButton
        function DP_LoadDataButtonPushed(app, event)
            path = app.DP_DatasetFolderEditField.Value;
            
            if isempty(path)
                uialert(app.TSADPlatformUIFigure, "No valid dataset folder selected!", ...
                        "Dataset Error", Icon="error");
                return;
            end

            if ~isfolder(path)
                path = fullfile(pwd, path);
                if ~isfolder(path)
                    uialert(app.TSADPlatformUIFigure, "No valid dataset folder selected!", ...
                            "Dataset Error", Icon="error");
                    return;
                end
            end
            
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Loading Dataset ...", ...
                              Indeterminate="on");
            drawnow;

            % Load dataset
            app.loadDataset(path);
            
            % Show statistical dataset features in plot
            app.showDatasetParameters();

            % Rreprocess data
            app.preprocessAndSplitDataset(true);
            
            % Close uiprogressbar
            close(app.progressDialog);
        end

        % Value changed function: DP_PreprocessingMethodDropDown
        function DP_PreprocessingMethodDropDownValueChanged(app, event)
            if ~app.info_dataLoaded
                return;
            end

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                              Indeterminate="on");
            drawnow;
            
            app.preprocessAndSplitDataset(true);
            
            close(app.progressDialog);
        end

        % Value changed function: DP_SplitTestSetForDynamicSwitchCheckBox
        function DP_SplitTestSetForDynamicSwitchCheckBoxValueChanged(app, event)
            value = app.DP_SplitTestSetForDynamicSwitchCheckBox.Value;
            app.toggleDynamicSwitchPanel(value);
            
            if value
                app.DP_RatioTestSwitchLabel.Enable = "on";
                app.DP_RatioTestSwitchEditField.Enable = "on";
            else
                app.DP_RatioTestSwitchLabel.Enable = "off";
                app.DP_RatioTestSwitchEditField.Enable = "off";
            end

            if ~app.info_testDataLoaded
                return;
            end

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                              Indeterminate="on");
            drawnow;
            
            app.preprocessAndSplitDataset(false);
            
            close(app.progressDialog);
        end

        % Value changed function: DP_RatioTestSwitchEditField
        function DP_RatioTestSwitchEditFieldValueChanged(app, event)
            if ~app.info_testDataLoaded
                return;
            end
            
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                              Indeterminate="on");
            drawnow;
            
            app.preprocessAndSplitDataset(false);
            
            close(app.progressDialog);
        end

        % Value changed function: DP_UseAnomalousValidationSetCheckBox
        function DP_UseAnomalousValidationSetCheckBoxValueChanged(app, event)
            if app.DP_UseAnomalousValidationSetCheckBox.Value
                app.DP_RatioTestValLabel.Enable = "on";
                app.DP_RatioTestValEditField.Enable = "on";
            else
                app.DP_RatioTestValLabel.Enable = "off";
                app.DP_RatioTestValEditField.Enable = "off";
            end

            if ~app.info_testDataLoaded
                return;
            end

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                              Indeterminate="on");
            drawnow;

            app.preprocessAndSplitDataset(false);
            
            close(app.progressDialog);
        end

        % Value changed function: DP_RatioTestValEditField
        function DP_RatioTestValEditFieldValueChanged(app, event)
            if ~app.info_testDataLoaded
                return;
            end
            
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                              Indeterminate="on");
            drawnow;
            
            app.preprocessAndSplitDataset(false);
            
            close(app.progressDialog);
        end

        % Value changed function: DP_SelectedFileTrainDropDown
        function DP_SelectedFileTrainDropDownValueChanged2(app, event)
            app.updatePreviewData("train");
        end

        % Value changed function: DP_SelectedChannelTrainDropDown
        function DP_SelectedChannelTrainDropDownValueChanged2(app, event)
            app.updatePreviewData("train");
        end

        % Value changed function: DP_SelectedFileTestDropDown
        function DP_SelectedFileTestDropDownValueChanged2(app, event)
            app.updatePreviewData("test");
        end

        % Value changed function: DP_SelectedChannelTestDropDown
        function DP_SelectedChannelTestDropDownValueChanged2(app, event)
            app.updatePreviewData("test");
        end

        % Value changed function: DP_SelectedFileTestValDropDown
        function DP_SelectedFileTestValDropDownValueChanged(app, event)
            app.updatePreviewData("test-val");
        end

        % Value changed function: DP_SelectedChannelTestValDropDown
        function DP_SelectedChannelTestValDropDownValueChanged(app, event)
            app.updatePreviewData("test-val");
        end

        % Value changed function: DP_AugmentationIntensitySlider
        function DP_AugmentationIntensitySliderValueChanged(app, event)
            if ~app.info_dataLoaded
                return;
            end

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                              Indeterminate="on");
            drawnow;

            app.preprocessAndSplitDataset(true);

            close(app.progressDialog);            
        end

        % Value changed function: DP_AugmentationModeDropDown
        function DP_AugmentationModeDropDownValueChanged(app, event)
            %changing the visual of the slider depending on the transformation chosen
            app.updateDataAugmentationSlider();

            if ~app.info_dataLoaded
                return;
            end

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                              Indeterminate="on");
            drawnow; 

            app.preprocessAndSplitDataset(true);

            close(app.progressDialog);
        end

        % Value changed function: DP_AugmentedTrainingCheckBock
        function DP_AugmentedTrainingCheckBockValueChanged(app, event)
            if ~app.info_trainDataLoaded
                return;
            end
            
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Preprocessing Dataset ...", ...
                              Indeterminate="on");
            drawnow;          
            
            app.preprocessAndSplitDataset(true);

            close(app.progressDialog);            
        end

        % Menu selected function: TD_ViewUpdateModelParametersMenu
        function TD_ViewUpdateModelParametersMenuSelected(app, event)
            selection = app.TD_ModelsListBox.Value;
            if isempty(selection)
                uialert(app.TSADPlatformUIFigure, "No model selected for viewing! Select model first.", ...
                        "Model Viewer Error", Icon="error");
                return;
            end
            if numel(selection) > 1
                uialert(app.TSADPlatformUIFigure, "Can't disply multiple models at once! Please select just a single model.", ...
                        "Model Viewer Error", Icon="error");
                return;
            end

            modelID = selection{1};

            parameters = app.Models.(modelID).parameters;
            NNLayers = app.Models.(modelID).getNeuralNetworkLayers(); % Returns empty if model is no NN
            app.popup_ModelParametersViewAndUpdate = ModelParametersViewAndUpdate(app, modelID, parameters, NNLayers);
        end

        % Menu selected function: TD_ChangeScoringFunctionMenu
        function TD_ChangeScoringFunctionMenuSelected(app, event)
            models = app.T_ModelsListBox.Value;
            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No models selected", ...
                        "Scoring Function Update Error", Icon="error");
                return;
            end


            if ~isempty(app.popup_ScoringFunctionSelection)
                app.popup_ScoringFunctionSelection.delete();
            end
            app.popup_ScoringFunctionSelection = ScoringFunctionSelection(app);
        end

        % Menu selected function: TD_UpdateStaticThresholdsMenu
        function TD_UpdateStaticThresholdsMenuSelected(app, event)
            models = app.T_ModelsListBox.Value;
   
            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No models selected.", ...
                        "Model Error", Icon="error");
                return;
            end
            
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Updating Static Thresholds ...", ...
                              Indeterminate="on");
            drawnow;
            
            app.updateThresholdsForSelection(models);

            close(app.progressDialog);
            uialert(app.TSADPlatformUIFigure, "Successfully updated static thresholds.", ...
                    "Update Successful", Icon="success");
        end

        % Button pushed function: TD_ImportTrainedButton
        function TD_ImportTrainedButtonPushed(app, event)
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Loading Models ...", ...
                              Indeterminate="on");
            drawnow;

            errorOccured = app.importModels();

            close(app.progressDialog);

            if errorOccured
                uialert(app.TSADPlatformUIFigure, "Invalid models file selected.", ...
                        "Import Error", Icon="error");
            end
        end

        % Button pushed function: TD_ExportTrainedButton
        function TD_ExportTrainedButtonPushed(app, event)
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Updating Static Thresholds ...", ...
                              Indeterminate="on");
            drawnow;
            
            status = app.exportModels();

            close(app.progressDialog);

            if ~strcmp(status, "success")
                uialert(app.TSADPlatformUIFigure, status, ...
                        "Export Error", Icon="error");
            end
        end

        % Callback function
        function D_ShowDetectionMenuSelected(app, event)
            selection = app.TD_ModelsListBox.Value;
            
            if numel(selection) ~= 1
                return;
            end
            
            testFileVarName = app.TD_TestDataFileDropDown.Value;

            if ~isfield(app.detectionStorage, testFileVarName)
                return;
            end

            if ~isfield(app.detectionStorage.(testFileVarName), selection.id)
                return;
            end

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Loading Detection ...", ...
                              Indeterminate="on");
            drawnow;

            timeSeriesTest = app.detectionStorage.(testFileVarName).(selection.id).timeSeriesTest;
            anomalyScores = app.detectionStorage.(testFileVarName).(selection.id).anomalyScores;
            labels = app.detectionStorage.(testFileVarName).(selection.id).labels;
            trainedModel = app.trainedModels.(selection.id);
            
            app.applyThresholdAndStore(trainedModel, anomalyScores, timeSeriesTest, labels, ...
                                testFileVarName, true, false);

            app.updateTrainingAndDetectionPanel();

            app.info_detectionShowing = true;

            close(app.progressDialog);
        end

        % Value changed function: TD_SortByDropDown
        function TD_SortByDropDownValueChanged(app, event)
            app.updateTrainingAndDetectionPanel();
        end

        % Value changed function: TD_TestDataFileDropDown
        function TD_TestDataFileDropDownValueChanged(app, event)
            % TODO 2
            app.updateTinyDataPreview();
            
            testFileVarName = genvarname(app.TD_TestDataFileDropDown.Value);
            
            if isempty(fieldnames(app.detectionStorage)) || ...
                    isempty(app.currentDetection_trainedModel) || ...
                    ~isfield(app.detectionStorage, testFileVarName) || ...
                    ~isfield(app.detectionStorage.(testFileVarName), app.currentDetection_trainedModel.modelOptions.id)
                app.clearDetectionPlots();
                app.updateTrainingAndDetectionPanel();
                return;
            end
            
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Loading Detection ...", ...
                              Indeterminate="on");
            drawnow;

            timeSeries = app.detectionStorage.(testFileVarName).(app.currentDetection_trainedModel.modelOptions.id).timeSeriesTest;
            anomalyScores = app.detectionStorage.(testFileVarName).(app.currentDetection_trainedModel.modelOptions.id).anomalyScores;
            labels = app.detectionStorage.(testFileVarName).(app.currentDetection_trainedModel.modelOptions.id).labels;
            
            app.applyThresholdAndStore(app.currentDetection_trainedModel, ...
                                anomalyScores, ...
                                timeSeries, ...
                                labels, ...
                                testFileVarName, true, false);
            
            app.updateTrainingAndDetectionPanel();

            close(app.progressDialog);
        end

        % Value changed function: TD_ThresholdDropDown
        function TD_ThresholdDropDownValueChanged(app, event)
            % TODO 4
            app.toggleDynamicThresholdSliders(app.TD_ThresholdDropDown.Value);
            
            if ~app.info_detectionShowing
                return;
            end

            if ~app.info_testDataLoaded
                return;
            end

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Updating Detection ...", ...
                              Indeterminate="on");
            drawnow;

            if ~isempty(app.currentDetection_anomalyScores)
                app.applyThresholdAndStore(app.currentDetection_trainedModel, ...
                                    app.currentDetection_anomalyScores, ...
                                    app.currentDetection_TSTest, ...
                                    app.currentDetection_labels, ...
                                    genvarname(app.TD_TestDataFileDropDown.Value), ...
                                    true, false);
            end


            app.updateTrainingAndDetectionPanel();
            
            close(app.progressDialog);
        end

        % Value changed function: TD_AnomalyPaddingSlider
        function TD_AnomalyPaddingSliderValueChanged(app, event)
            % TODO 5
            app.settings_dynamicThreshold.anomalyPadding = app.TD_AnomalyPaddingSlider.Value;
            
            if ~app.info_detectionShowing
                return;
            end

            if ~app.info_testDataLoaded
                return;
            end
            
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Updating Detection ...", ...
                              Indeterminate="on");
            drawnow;

            if ~isempty(app.currentDetection_anomalyScores)
                app.applyThresholdAndStore(app.currentDetection_trainedModel, ...
                                    app.currentDetection_anomalyScores, ...
                                    app.currentDetection_TSTest, ...
                                    app.currentDetection_labels, ...
                                    genvarname(app.TD_TestDataFileDropDown.Value), ...
                                    true, false);
            end

            app.updateTrainingAndDetectionPanel();
            
            close(app.progressDialog);
        end

        % Value changed function: TD_WindowSizeSlider
        function TD_WindowSizeSliderValueChanged(app, event)
            % TODO 6
            app.settings_dynamicThreshold.windowSize = app.TD_WindowSizeSlider.Value;
            
            if ~app.info_detectionShowing
                return;
            end
            
            if ~app.info_testDataLoaded
                return;
            end

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Updating Detection ...", ...
                              Indeterminate="on");
            drawnow;

            if ~isempty(app.currentDetection_anomalyScores)
                app.applyThresholdAndStore(app.currentDetection_trainedModel, ...
                                    app.currentDetection_anomalyScores, ...
                                    app.currentDetection_TSTest, ...
                                    app.currentDetection_labels, ...
                                    genvarname(app.TD_TestDataFileDropDown.Value), ...
                                    true, false);
            end

            app.updateTrainingAndDetectionPanel();
            
            close(app.progressDialog);
        end

        % Value changed function: TD_ZRangeSlider
        function TD_ZRangeSliderValueChanged(app, event)
            % TODO 7
            app.settings_dynamicThreshold.zRange = app.TD_ZRangeSlider.Value;
            
            if ~app.info_detectionShowing
                return;
            end

            if ~app.info_testDataLoaded
                return;
            end

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Updating Detection ...", ...
                              Indeterminate="on");
            drawnow;

            if ~isempty(app.currentDetection_anomalyScores)
                app.applyThresholdAndStore(app.currentDetection_trainedModel, ...
                                    app.currentDetection_anomalyScores, ...
                                    app.currentDetection_TSTest, ...
                                    app.currentDetection_labels, ...
                                    genvarname(app.TD_TestDataFileDropDown.Value), ...
                                    true, false);
            end

            app.updateTrainingAndDetectionPanel();
            
            close(app.progressDialog);
        end

        % Callback function
        function TD_RunDetectionForSelectedButtonPushed(app, event)
            if ~app.info_testDataLoaded
                uialert(app.TSADPlatformUIFigure, "No test data loaded!", ...
                        "Detection Error", Icon="error");
                return;
            end
            
            if isempty(fieldnames(app.trainedModels))
                uialert(app.TSADPlatformUIFigure, "No trained models found. Train them first on the training panel.", ...
                        "Model Error", Icon="error");
                return;
            end

            models = app.TD_ModelsListBox.Value;

            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No models selected for detection", ...
                        "Detection Error", Icon="error");
                return;
            end

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Running Detection ...", ...
                              Indeterminate="on");
            drawnow;

            if ~app.TD_UpdateThresholdOnlyCheckBox.Value
                app.runDetection(models);
                app.info_detectionShowing = true;
            else
                app.updateAppliedThreshold(models);
            end
            
            close(app.progressDialog);

            if ~app.info_dimensionMismatchDetected
                uialert(app.TSADPlatformUIFigure, "Detection finished successfully!", ...
                        "Detection Successful", Icon="success");
            end
        end

        % Callback function
        function TD_RunDetectionForAllButtonPushed(app, event)
            if ~app.info_testDataLoaded
                uialert(app.TSADPlatformUIFigure, "No test data loaded!", ...
                        "Detection Error", Icon="error");
                return;
            end
            
            if isempty(fieldnames(app.trainedModels))
                uialert(app.TSADPlatformUIFigure, "No trained models found. Train them first on the training panel.", ...
                        "Model Error", Icon="error");
                return;
            end

            models = app.TD_ModelsListBox.ItemsData;

            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No models selected for detection", ...
                        "Detection Error", Icon="error");
                return;
            end

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Running Detection ...", ...
                              Indeterminate="on");
            drawnow;
            
            if ~app.TD_UpdateThresholdOnlyCheckBox.Value
                app.runDetection(models);
                app.info_detectionShowing = true;
            else
                app.updateAppliedThreshold(models);
            end
            
            close(app.progressDialog);
            if ~app.info_dimensionMismatchDetected
                uialert(app.TSADPlatformUIFigure, "Detection finished successfully!", ...
                        "Detection Successful", Icon="success");
            end
        end

        % Callback function
        function TD_RunForAllModelsOnAllFilesButtonPushed(app, event)
            if ~app.info_testDataLoaded
                uialert(app.TSADPlatformUIFigure, "No test data loaded!", ...
                        "Detection Error", Icon="error");
                return;
            end
            
            if isempty(fieldnames(app.trainedModels))
                uialert(app.TSADPlatformUIFigure, "No trained models found. Train them first on the training panel.", ...
                        "Model Error", Icon="error");
                return;
            end

            models = app.TD_ModelsListBox.ItemsData;

            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No models selected for detection", ...
                        "Detection Error", Icon="error");
                return;
            end

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Running Detection ...", ...
                              Indeterminate="on");
            drawnow;
            
            if ~app.TD_UpdateThresholdOnlyCheckBox.Value
                app.runDetectionForAllFiles(models);
                app.info_detectionShowing = true;
            else
                app.updateAppliedThresholdForAllFiles(models);
            end
            
            close(app.progressDialog);
            if ~app.info_dimensionMismatchDetected
                uialert(app.TSADPlatformUIFigure, "Detection finished successfully!", ...
                        "Detection Successful", Icon="success");
            end
        end

        % Callback function
        function TD_RunForSelectedModelsOnAllFilesButtonPushed(app, event)
            if ~app.info_testDataLoaded
                uialert(app.TSADPlatformUIFigure, "No test data loaded!", ...
                        "Detection Error", Icon="error");
                return;
            end
            
            if isempty(fieldnames(app.trainedModels))
                uialert(app.TSADPlatformUIFigure, "No trained models found. Train them first on the training panel.", ...
                        "Model Error", Icon="error");
                return;
            end

            models = app.TD_ModelsListBox.Value;

            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No models selected for detection", ...
                        "Detection Error", Icon="error");
                return;
            end

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Running Detection ...", ...
                              Indeterminate="on");
            drawnow;
            
            if ~app.TD_UpdateThresholdOnlyCheckBox.Value
                app.runDetectionForAllFiles(models);
                app.info_detectionShowing = true;
            else
                app.updateAppliedThresholdForAllFiles(models);
            end
            
            close(app.progressDialog);
            if ~app.info_dimensionMismatchDetected
                uialert(app.TSADPlatformUIFigure, "Detection finished successfully!", ...
                        "Detection Successful", Icon="success");
            end
        end

        % Button pushed function: TD_ScoresExportTableButton
        function TD_ScoresExportTableButtonPushed(app, event)
            app.saveScoresTable(app.TD_ScoresUITable, "Export Scores", "Scores");
        end

        % Button pushed function: TD_AverageScoresExportTableButton
        function TD_AverageScoresExportTableButtonPushed(app, event)
            app.saveScoresTable(app.TD_AverageScoresUITable, "Export Average Scores", "Average Scores");
        end

        % Button pushed function: TD_CompTimeMetricSelectionButton
        function TD_CompTimeMetricSelectionButtonPushed(app, event)
            % TODO 9
            app.popup_CompTimeMetricSelection = CompTimeMetricSelection(app, app.compTimeMetrics);
        end

        % Callback function
        function SD_ImportTrainedModelsButtonPushed(app, event)
            app.importTrainedModels();
        end

        % Callback function
        function SD_ExportTrainedModelsPushed(app, event)
            app.exportModels();
        end

        % Callback function
        function SD_RunDetectionForSelectedButtonPushed(app, event)
            if ~app.info_testDataLoaded
                uialert(app.TSADPlatformUIFigure, "No test data loaded!", ...
                        "Simulink Detection Error", Icon="error");
                return;
            end

            if isempty(fieldnames(app.trainedModels))
                uialert(app.TSADPlatformUIFigure, "No trained models found. Train them first on the training panel.", ...
                        "Model Error", Icon="error");
                return;
            end
            
            model = app.SD_TrainedModelsListBox.Value;

            if numel(model) > 1
                uialert(app.TSADPlatformUIFigure, "Please select just one model for Simulink detection", ...
                        "Simulink Detection Error", Icon="error");
                return;
            elseif isempty(model)
                uialert(app.TSADPlatformUIFigure, "No model selected for Simulink detection", ...
                        "Simulink Detection Error", Icon="error");
                return;
            end

            modelID = model.id;
            mdl = app.trainedModels.(modelID);

            if ~isfield(mdl, "staticThresholds")
                uialert(app.TSADPlatformUIFigure, "No thresholds available. Some thresholds require the use of an anomalous validation set to be computed (see Preprocessing panel).", ...
                        "Simulink Detection Error", Icon="error");
                return;
            end

            staticThresholds = mdl.staticThresholds;
            
            thresholdItem = getThresholdId(app.SD_ThresholdDropDown.Value);

            if isfield(staticThresholds, thresholdItem)
                selectedThreshold = staticThresholds.(thresholdItem);
            else
                uialert(app.TSADPlatformUIFigure, "Threshold not available. Some thresholds require the use of an anomalous validation set to be computed (see Preprocessing panel).", ...
                        "Simulink Detection Error", Icon="error");
                return;
            end

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Running Detection on Simulink ...", ...
                              Indeterminate="on");
            drawnow;

            wSize = mdl.modelOptions.hyperparameters.windowSize;
            mdlType = mdl.modelOptions.modelType;
            netPath = app.saveModelForSim(mdl.Mdl, modelID);
            currentMdlLabel = app.trainedModels.(modelID).modelOptions.label;
            app.SD_DetectionShownForLabel.Text = sprintf("Detection shown for: %s", currentMdlLabel);
            if strcmp(mdlType, "predictive")
                simModel = "PredictiveSim";
                load_system(simModel)
                path = strcat(simModel, "/Prediction model");
                set_param(path,"BufferLength",num2str(wSize))
                set_param(path,"NetworkFilePath",netPath)
            else
                simModel = "AutoEncoderPredictSim";
                load_system(simModel)
                path = strcat(simModel, "/Reconstruction model");
                set_param(path,"BufferLength",num2str(wSize))
                set_param(path,"NetworkFilePath",netPath)
            end
            if app.SD_OpenmodelCheckBox.Value == 1
                open(simModel)
            end
            selectedItem = app.SD_FaultyDataSelectionDropDown.Value;
            [~, data_idx] = ismember(selectedItem, app.SD_FaultyDataSelectionDropDown.Items);
            testingData = app.data_dataTest(data_idx);
            testingLabels = app.data_labelsTest(data_idx);
            duration = numel(testingLabels{1});
            assignin("base", "data", timeseries(testingData{1}))
            assignin("base", "labels", timeseries(testingLabels{1}))
            assignin("base", "threshold", selectedThreshold)


            % Plot true data and found anomalies
            simulated = sim(simModel, "StopTime", num2str(duration));
            plot(app.SD_FoundAnomaliesUIAxes, simulated.yout{1}.Values.Data(:,1))
            hold(app.SD_FoundAnomaliesUIAxes, "on")
            plot(app.SD_FoundAnomaliesUIAxes, simulated.yout{1}.Values.Data(:,2), "r")
            hold(app.SD_FoundAnomaliesUIAxes, "off")
            legend(app.SD_FoundAnomaliesUIAxes, ["Data", "Found anomalies"])

            plot(app.SD_TrueAnomaliesUIAxes, simulated.yout{2}.Values.Data(:,1))
            hold(app.SD_TrueAnomaliesUIAxes, "on")
            plot(app.SD_TrueAnomaliesUIAxes, simulated.yout{2}.Values.Data(:,2), "r")
            hold(app.SD_TrueAnomaliesUIAxes, "off")
            legend(app.SD_TrueAnomaliesUIAxes, ["Data", "True anomalies"])

            app.SD_FoundAnomaliesUIAxes.Visible = "on";
            app.SD_TrueAnomaliesUIAxes.Visible = "on";


            close(app.progressDialog);
            uialert(app.TSADPlatformUIFigure, "Detection finished successfully!", ...
                    "Detection Successful", Icon="success");
        end

        % Value changed function: DS_SelectedFileTestSwitchDropDown
        function DS_SelectedFileTestSwitchDropDownValueChanged(app, event)
            app.updatePreviewData("test-switch");
        end

        % Value changed function: DS_SelectedChannelTestSwitchDropDown
        function DS_SelectedChannelTestSwitchDropDownValueChanged(app, event)
            app.updatePreviewData("test-switch");
        end

        % Button pushed function: DS_TrainClassifierButton
        function DS_TrainClassifierButtonPushed(app, event)
            if ~app.DP_SplitTestSetForDynamicSwitchCheckBox.Value
                uialert(app.TSADPlatformUIFigure, "Please check the 'Split Test Set for Dynamic Switch' Checkbox on the Preprocessing panel", ...
                        "Data Error", Icon="error");
                return;
            end

            if isempty(fieldnames(app.trainedModels))
                uialert(app.TSADPlatformUIFigure, "No trained models found. Train them first on the training panel.", ...
                        "Model Error", Icon="error");
                return;
            end

            answer = uiconfirm(app.TSADPlatformUIFigure, "Did you already run the detection for all files on the Detection Panel?", ...
                                "Detection Run?", Options={'Yes', 'No'}, DefaultOption='No', CancelOption='No');
            if ~strcmp(answer, "Yes")
                return;
            end
            
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Training Dynamic Switch Classifier ...", ...
                              Indeterminate="on");
            drawnow;

            app.ds_bestModels = app.findBestModels(app.DS_MetricDropDown.Value);
            
            fileNamesCell = fieldnames(app.ds_bestModels);
            numTrainFiles = numel(fileNamesCell);
            fileNames = strings(numTrainFiles, 1);
            bestModels = strings(numTrainFiles, 1);
            for i = 1:numTrainFiles
                fileNames(i) = fileNamesCell{i};
                bestModels(i) = app.ds_bestModels.(fileNamesCell{i}).label;
            end
            app.DS_TrainingDataLabelsUITable.Data = [fileNames, bestModels];

            app.ds_classifier = trainDynamicSwitchClassifier(app.data_dataTest, app.ds_bestModels, app.data_fileNamesTest);
            
            close(app.progressDialog);
            uialert(app.TSADPlatformUIFigure, "Successfully trained Dynamic Switch Classifier.", ...
                    "Training Successful", Icon="success");
        end

        % Button pushed function: DS_RunEvaluationsButton
        function DS_RunEvaluationsButtonPushed(app, event)
            if ~app.DP_SplitTestSetForDynamicSwitchCheckBox.Value
                uialert(app.TSADPlatformUIFigure, "Please check the 'Split Test Set for Dynamic Switch' Checkbox on the Preprocessing panel", ...
                        "Data Error", Icon="error");
                return;
            end       

            if isempty(fieldnames(app.trainedModels))
                uialert(app.TSADPlatformUIFigure, "No trained models found. Train them first on the training panel.", ...
                        "Model Error", Icon="error");
                return;
            end 
            
            if isempty(app.ds_classifier)
                uialert(app.TSADPlatformUIFigure, "No classifier trained!", ...
                        "Classifier Error", Icon="error");
                return;
            end

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Evaluating Dynamic Switch ...", ...
                              Indeterminate="on");
            drawnow;

            fprintf("\n\n### Evaluating dynamic switch ... ###\n\n");
            
            [finalTable, selectedModels] = evaluateDynamicSwitch(app.ds_classifier, ...
                                                                 app.trainedModels, ...
                                                                 app.data_dataTestSwitch, ...
                                                                 app.data_labelsTestSwitch, ...
                                                                 app.data_fileNamesTestSwitch, ...
                                                                 getThresholdId(app.TD_ThresholdDropDown.Value), ...
                                                                 app.settings_dynamicThreshold);

            app.DS_ResultsUITable.Data = finalTable;
            app.DS_ResultsUITable.ColumnName = finalTable.Properties.VariableNames;

            app.DS_TestingDataPredictionsUITable.Data = [app.data_fileNamesTestSwitch, selectedModels];

            removeStyle(app.DS_ResultsUITable);
            app.highlightTableMaxima(app.DS_ResultsUITable, "row-wise");
            
            fprintf("\n\n### Finished evaluating dynamic switch successfully!\n\n");
            close(app.progressDialog);
            uialert(app.TSADPlatformUIFigure, "Successfully finished evaluation.", ...
                    "Evaluation Successful", Icon="success");
        end

        % Button pushed function: DS_ExportButton
        function DS_ExportButtonPushed(app, event)
            app.saveScoresTable(app.DS_ResultsUITable, "Export Dynamic Switch Results", "Dynamic_Switch_Results");
        end

        % Button pushed function: AR_BrowseDatasetButton
        function AR_BrowseDatasetButtonPushed(app, event)
            f = figure("Renderer", "painters", "Position", [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile/uiputfile
            path = uigetdir(pwd);
            delete(f);
            if ~isequal(path, 0)
                app.AR_DatasetFolderEditField.Value = path;
            end
        end

        % Value changed function: AR_UseAnomalousValidationSetCheckBox
        function AR_UseAnomalousValidationSetCheckBoxValueChanged(app, event)
            if app.AR_UseAnomalousValidationSetCheckBox.Value
                app.AR_RatioTestValLabel.Enable = "on";
                app.AR_RatioTestValEditField.Enable = "on";
            else
                app.AR_RatioTestValLabel.Enable = "off";
                app.AR_RatioTestValEditField.Enable = "off";         
            end
        end

        % Value changed function: AR_AugmentationModeDropDown
        function AR_AugmentationModeDropDownValueChanged(app, event)
            value = app.AR_AugmentationModeDropDown.Value;
            
            %changing the visual of the slider depending on the transformation chosen

            switch value
                case "none"
                    app.AR_IntensitySlider.Visible="off";
                    app.AR_IntensityLabel.Visible="off";
                    app.AR_AugmentedTrainingCheckBock.Visible = "off";
                case "white noise"
                    app.AR_IntensitySlider.Limits = [0, 1];
                    app.AR_IntensityLabel.Text = "Standard deviation";
                    app.AR_IntensitySlider.Visible="on";
                    app.AR_IntensityLabel.Visible="on";
                    app.AR_AugmentedTrainingCheckBock.Visible = "on";
                case "random walk"
                    app.AR_IntensitySlider.Limits = [0, 1];
                    app.AR_IntensityLabel.Text = "Standard deviation";
                    app.AR_IntensitySlider.Visible="on";
                    app.AR_IntensityLabel.Visible="on";
                    app.AR_AugmentedTrainingCheckBock.Visible = "on";
                case "global shift"
                    app.AR_IntensitySlider.Limits = [-100, 100];
                    app.AR_IntensityLabel.Text = "Shift in % of the max value";
                    app.AR_IntensitySlider.Visible="on";
                    app.AR_IntensityLabel.Visible="on";
                    app.AR_AugmentedTrainingCheckBock.Visible = "on";
                case "attenuate extremum"
                    app.AR_IntensitySlider.Limits = [0, 100];
                    app.AR_IntensityLabel.Text = "Strength of the transformation in %";
                    app.AR_IntensitySlider.Visible="on";
                    app.AR_IntensityLabel.Visible="on";
                    app.AR_AugmentedTrainingCheckBock.Visible = "on";
            end
        end

        % Button pushed function: AR_RunEvaluationButton
        function AR_RunEvaluationButtonPushed(app, event)
            datasetPath = app.AR_DatasetFolderEditField.Value;
            
            if isempty(datasetPath)
                uialert(app.TSADPlatformUIFigure, "No valid dataset folder selected!", ...
                        "Dataset Error", Icon="error");
                return;
            end

            if ~isfolder(datasetPath)
                datasetPath = fullfile(pwd, datasetPath);
                if ~isfolder(datasetPath)
                    uialert(app.TSADPlatformUIFigure, "No valid dataset folder selected!", ...
                            "Dataset Error", Icon="error");
                    return;
                end
            end

            % Load all models as added on the Training tab
            models = app.T_ModelsListBox.ItemsData;

            if isempty(models)
                uialert(app.TSADPlatformUIFigure, "No models found for evaluation. Add them on the Training panel.", ...
                        "Model Error", Icon="error");
                return;
            end 
 
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Running Auto-Evaluation on Dataset ...", ...
                              Indeterminate="on");
            drawnow;

            if app.AR_UseAnomalousValidationSetCheckBox.Value
                ratioTestVal = app.AR_RatioTestValEditField.Value;
            else
                ratioTestVal = 0;
            end
            preprocMethod = app.AR_PreprocessingMethodDropDown.Value;

            if app.AR_TrainingPlotsCheckBox.Value
                trainingPlots = "training-progress";
            else
                trainingPlots = "none";
            end

            if app.AR_AugmentationModeDropDown.Value ~= "none"
                augmentationEnabled = true;
            else
                augmentationEnabled = false;
            end
            augmentationMode = app.AR_AugmentationModeDropDown.Value;
            augmenteationIntensity = app.AR_IntensitySlider.Value;
            augmentedTrainingEnabled = app.AR_AugmentedTrainingCheckBock.Value;

            % TODO: set thresholds, move this function somewhere else

            finalTable = autoRunWrapper(datasetPath, models, preprocMethod, ...
                                        ratioTestVal, thresholds, ...
                                        app.settings_dynamicThreshold, ...
                                        trainingPlots, app.settings_parallelEnabled, ...
                                        augmentationEnabled, ...
                                        augmentationMode, ...
                                        augmenteationIntensity, ...
                                        augmentedTrainingEnabled, ...
                                        app.AR_GetComputationTimeCheckBox.Value);

            app.AR_ResultsUITable.Data = finalTable;
            app.AR_ResultsUITable.ColumnName = finalTable.Properties.VariableNames;

            removeStyle(app.AR_ResultsUITable);
            app.highlightTableMaxima(app.AR_ResultsUITable, "row-wise");
            
            close(app.progressDialog);
            uialert(app.TSADPlatformUIFigure, "Successfully finished evaluating all models on selected dataset. Results saved to Dataset_Sweep_Results folder in the current Matlab folder", ...
                    "Auto Run Successful", Icon="success");
        end

        % Button pushed function: AR_VisualizeDataButton
        function AR_VisualizeDataButtonPushed(app, event)
            datasetPath = app.AR_DatasetFolderEditField.Value;
            
            if isempty(datasetPath)
                uialert(app.TSADPlatformUIFigure, "No valid dataset folder selected!", ...
                        "Dataset Error", Icon="error");
                return;
            end

            if ~isfolder(datasetPath)
                datasetPath = fullfile(pwd, datasetPath);
                if ~isfolder(datasetPath)
                    uialert(app.TSADPlatformUIFigure, "No valid dataset folder selected!", ...
                            "Dataset Error", Icon="error");
                    return;
                end
            end

            if ~strcmp(app.AR_AugmentationModeDropDown.Value, "none")
                augmentationEnabled = true;
            else
                augmentationEnabled = false;
            end

            app.popup_DatasetVisualization = DatasetVisualization(datasetPath, augmentationEnabled, ...
                app.AR_AugmentationModeDropDown.Value, app.AR_IntensitySlider.Value, app.AR_AugmentedTrainingCheckBock.Value);
        end

        % Button pushed function: AR_ExportTableButton
        function AR_ExportTableButtonPushed(app, event)
            app.saveScoresTable(app.AR_ResultsUITable, "Export Auto Run Results", "Auto_Run_Average_Scores");
        end

        % Button pushed function: TD_LoadDefaultButton
        function TD_LoadDefaultButtonPushed(app, event)
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Loading Default Config ...", ...
                              Indeterminate="on");
            drawnow;
            
            app.loadModelConfigDefault();
            app.updateTrainingAndDetectionPanel();

            close(app.progressDialog);
        end

        % Button pushed function: TD_LoadManuallyButton
        function TD_LoadManuallyButtonPushed(app, event)
            app.popup_ModelSelection = ModelSelection(app);
        end

        % Button pushed function: TD_LoadConfigButton
        function TD_LoadConfigButtonPushed(app, event)
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Loading Config ...", ...
                              Indeterminate="on");
            drawnow;
            
            app.loadModelConfigFile();
            app.updateTrainingAndDetectionPanel();

            close(app.progressDialog);
        end

        % Button pushed function: TD_ExportConfigButton
        function TD_ExportConfigButtonPushed(app, event)
            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Exporting Config ...", ...
                              Indeterminate="on");
            drawnow;
            
            app.exportModelConfig();

            close(app.progressDialog);
        end

        % Button pushed function: TD_FitAllButton
        function TD_FitAllButtonPushed(app, event)
            modelIDs = app.TD_ModelsListBox.ItemsData;
            
            if isempty(modelIDs)
                uialert(app.TSADPlatformUIFigure, "No models loaded!", ...
                        "Training Error", Icon="error");
                return;
            end


            % Open optimization window if enabled
            if app.TD_OptimizeCheckBox.Value
                app.popup_OptimizationConfigurator = OptimizationConfigurator(app, modelIDs);
                app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Optimizing Models ...", ...
                                                   Indeterminate="on");
                return;
            end


            % Otherwise just fit models

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Training Models ...", ...
                                               Indeterminate="on");
            drawnow;
            
            app.fitModels(modelIDs);

            close(app.progressDialog);
            uialert(app.TSADPlatformUIFigure, "Successfully trained models.", ...
                    "Training Successful", Icon="success");
        end

        % Button pushed function: TD_FitSelectionButton
        function TD_FitSelectionButtonPushed(app, event)
            modelIDs = app.TD_ModelsListBox.Value;
            
            if isempty(modelIDs)
                uialert(app.TSADPlatformUIFigure, "No models selected!", ...
                        "Training Error", Icon="error");
                return;
            end

            % Open optimization window if enabled
            if app.TD_OptimizeCheckBox.Value
                app.popup_OptimizationConfigurator = OptimizationWindow(app, modelIDs);
                app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Optimizing Models ...", ...
                                                   Indeterminate="on");
                return;
            end


            % Otherwise just fit models

            app.progressDialog = uiprogressdlg(app.TSADPlatformUIFigure, Title="Training Models ...", ...
                                               Indeterminate="on");
            drawnow;
            
            app.fitModels(modelIDs);

            close(app.progressDialog);
            uialert(app.TSADPlatformUIFigure, "Successfully trained models.", ...
                    "Training Successful", Icon="success");
        end

        % Button pushed function: TD_ClearAllButton
        function TD_ClearAllButtonPushed(app, event)
            app.Models = struct();
            app.updateTrainingAndDetectionPanel();
        end

        % Menu selected function: TD_DeleteMenu
        function TD_DeleteMenuSelected(app, event)
            modelsToDelete = app.TD_ModelsListBox.Value;
            for i = 1:numel(modelsToDelete)
                app.Models = rmfield(app.Models, modelsToDelete{i});
            end
            app.updateTrainingAndDetectionPanel();
        end

        % Selection changed function: TD_FileSelectionButtonGroup
        function TD_FileSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.TD_FileSelectionButtonGroup.SelectedObject;
            % TODO 1
        end

        % Button pushed function: TD_RunDetectionButton
        function TD_RunDetectionButtonPushed(app, event)
            app.runDetection();
            app.updateTrainingAndDetectionPanel();
        end

        % Menu selected function: TD_ShowDetectionMenu
        function TD_ShowDetectionMenuSelected(app, event)
            
        end

        % Selection changed function: TD_ModelSelectionButtonGroup
        function TD_ModelSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.TD_ModelSelectionButtonGroup.SelectedObject;
            % TODO 3
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TSADPlatformUIFigure = uifigure('Visible', 'off');
            app.TSADPlatformUIFigure.Name = 'TSAD Platform';
            app.TSADPlatformUIFigure.Icon = fullfile(pathToMLAPP, 'media', 'TSADPlatform_Icon.png');
            app.TSADPlatformUIFigure.CloseRequestFcn = createCallbackFcn(app, @TSADPlatformUIFigureCloseRequest, true);
            app.SettingsMenu = uimenu(app.TSADPlatformUIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.EnableParallelModeMenu = uimenu(app.SettingsMenu);
            app.EnableParallelModeMenu.MenuSelectedFcn = createCallbackFcn(app, @EnableParallelModeMenuSelected, true);
            app.EnableParallelModeMenu.Text = 'Enable Parallel Mode';
            app.MainPanel = uitabgroup(app.TSADPlatformUIFigure);
            app.DatasetPreparationTab = uitab(app.MainPanel);
            app.DatasetPreparationTab.Title = '1.1 Dataset Preparation';
            app.DatasetPreparationPanel = uipanel(app.DatasetPreparationTab);
            app.DP_PreprocessedTrainingDatasetPreviewUIAxes = uiaxes(app.DatasetPreparationPanel);
            title(app.DP_PreprocessedTrainingDatasetPreviewUIAxes, 'Training Dataset (processed)')
            xlabel(app.DP_PreprocessedTrainingDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.DP_PreprocessedTrainingDatasetPreviewUIAxes, 'Value')
            zlabel(app.DP_PreprocessedTrainingDatasetPreviewUIAxes, 'Z')
            app.DP_PreprocessedTestingDatasetPreviewUIAxes = uiaxes(app.DatasetPreparationPanel);
            title(app.DP_PreprocessedTestingDatasetPreviewUIAxes, 'Testing Dataset (processed)')
            xlabel(app.DP_PreprocessedTestingDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.DP_PreprocessedTestingDatasetPreviewUIAxes, 'Value')
            zlabel(app.DP_PreprocessedTestingDatasetPreviewUIAxes, 'Z')
            app.DP_PreprocessedTestValDatasetPreviewUIAxes = uiaxes(app.DatasetPreparationPanel);
            title(app.DP_PreprocessedTestValDatasetPreviewUIAxes, 'Anomalous Validation Dataset (processed)')
            xlabel(app.DP_PreprocessedTestValDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.DP_PreprocessedTestValDatasetPreviewUIAxes, 'Value')
            zlabel(app.DP_PreprocessedTestValDatasetPreviewUIAxes, 'Z')
            app.SelectedChannelLabel_3 = uilabel(app.DatasetPreparationPanel);
            app.SelectedChannelLabel_3.Text = 'Selected Channel:';
            app.DP_SelectedChannelTrainDropDown = uidropdown(app.DatasetPreparationPanel);
            app.DP_SelectedChannelTrainDropDown.Items = {};
            app.DP_SelectedChannelTrainDropDown.ValueChangedFcn = createCallbackFcn(app, @DP_SelectedChannelTrainDropDownValueChanged2, true);
            app.DP_SelectedChannelTrainDropDown.Value = {};
            app.SelectedFileLabel_3 = uilabel(app.DatasetPreparationPanel);
            app.SelectedFileLabel_3.Text = 'Selected File:';
            app.DP_SelectedFileTrainDropDown = uidropdown(app.DatasetPreparationPanel);
            app.DP_SelectedFileTrainDropDown.Items = {};
            app.DP_SelectedFileTrainDropDown.ValueChangedFcn = createCallbackFcn(app, @DP_SelectedFileTrainDropDownValueChanged2, true);
            app.DP_SelectedFileTrainDropDown.Value = {};
            app.SelectedChannelLabel_4 = uilabel(app.DatasetPreparationPanel);
            app.SelectedChannelLabel_4.Text = 'Selected Channel:';
            app.DP_SelectedChannelTestDropDown = uidropdown(app.DatasetPreparationPanel);
            app.DP_SelectedChannelTestDropDown.Items = {};
            app.DP_SelectedChannelTestDropDown.ValueChangedFcn = createCallbackFcn(app, @DP_SelectedChannelTestDropDownValueChanged2, true);
            app.DP_SelectedChannelTestDropDown.Value = {};
            app.SelectedFileLabel_4 = uilabel(app.DatasetPreparationPanel);
            app.SelectedFileLabel_4.Text = 'Selected File:';
            app.DP_SelectedFileTestDropDown = uidropdown(app.DatasetPreparationPanel);
            app.DP_SelectedFileTestDropDown.Items = {};
            app.DP_SelectedFileTestDropDown.ValueChangedFcn = createCallbackFcn(app, @DP_SelectedFileTestDropDownValueChanged2, true);
            app.DP_SelectedFileTestDropDown.Value = {};
            app.SelectedChannelLabel_5 = uilabel(app.DatasetPreparationPanel);
            app.SelectedChannelLabel_5.Text = 'Selected Channel:';
            app.DP_SelectedChannelTestValDropDown = uidropdown(app.DatasetPreparationPanel);
            app.DP_SelectedChannelTestValDropDown.Items = {};
            app.DP_SelectedChannelTestValDropDown.ValueChangedFcn = createCallbackFcn(app, @DP_SelectedChannelTestValDropDownValueChanged, true);
            app.DP_SelectedChannelTestValDropDown.Value = {};
            app.SelectedFileLabel_5 = uilabel(app.DatasetPreparationPanel);
            app.SelectedFileLabel_5.Text = 'Selected File:';
            app.DP_SelectedFileTestValDropDown = uidropdown(app.DatasetPreparationPanel);
            app.DP_SelectedFileTestValDropDown.Items = {};
            app.DP_SelectedFileTestValDropDown.ValueChangedFcn = createCallbackFcn(app, @DP_SelectedFileTestValDropDownValueChanged, true);
            app.DP_SelectedFileTestValDropDown.Value = {};
            app.DP_DatasetSelectionPanel = uipanel(app.DatasetPreparationPanel);
            app.DP_DatasetSelectionPanel.Title = 'Dataset Selection';
            app.DP_DatasetAttributesLabel = uilabel(app.DP_DatasetSelectionPanel);
            app.DP_DatasetAttributesLabel.Text = 'Dataset Attributes:';
            app.DP_DatasetAttributesUITable = uitable(app.DP_DatasetSelectionPanel);
            app.DP_DatasetAttributesUITable.ColumnName = {'Channel'; 'Minimum'; 'Maximum'; 'Mean'; 'Standard Deviation'};
            app.DP_DatasetAttributesUITable.RowName = {};
            app.DP_LoadDataButton = uibutton(app.DP_DatasetSelectionPanel, 'push');
            app.DP_LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @DP_LoadDataButtonPushed, true);
            app.DP_LoadDataButton.Tooltip = {'Load the selected dataset'};
            app.DP_LoadDataButton.Text = 'Load Data';
            app.DP_BrowseDatasetButton = uibutton(app.DP_DatasetSelectionPanel, 'push');
            app.DP_BrowseDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @DP_BrowseDatasetButtonPushed, true);
            app.DP_BrowseDatasetButton.Text = 'Browse...';
            app.DP_DatasetFolderLabel = uilabel(app.DP_DatasetSelectionPanel);
            app.DP_DatasetFolderLabel.Text = 'Dataset Folder:';
            app.DP_DatasetFolderEditField = uieditfield(app.DP_DatasetSelectionPanel, 'text');
            app.DP_DatasetProcessingPanel = uipanel(app.DatasetPreparationPanel);
            app.DP_DatasetProcessingPanel.Title = 'Data Processing';
            app.DP_RatioTestValEditField = uieditfield(app.DP_DatasetProcessingPanel, 'numeric');
            app.DP_RatioTestValEditField.Limits = [0.01 0.99];
            app.DP_RatioTestValEditField.ValueChangedFcn = createCallbackFcn(app, @DP_RatioTestValEditFieldValueChanged, true);
            app.DP_RatioTestValEditField.Enable = 'off';
            app.DP_RatioTestValEditField.Value = 0.2;
            app.DP_UseAnomalousValidationSetCheckBox = uicheckbox(app.DP_DatasetProcessingPanel);
            app.DP_UseAnomalousValidationSetCheckBox.ValueChangedFcn = createCallbackFcn(app, @DP_UseAnomalousValidationSetCheckBoxValueChanged, true);
            app.DP_UseAnomalousValidationSetCheckBox.Text = 'Use anomalous Validation Set';
            app.DP_RatioTestValLabel = uilabel(app.DP_DatasetProcessingPanel);
            app.DP_RatioTestValLabel.Enable = 'off';
            app.DP_RatioTestValLabel.Text = 'Ratio:';
            app.DP_SplitTestSetForDynamicSwitchCheckBox = uicheckbox(app.DP_DatasetProcessingPanel);
            app.DP_SplitTestSetForDynamicSwitchCheckBox.ValueChangedFcn = createCallbackFcn(app, @DP_SplitTestSetForDynamicSwitchCheckBoxValueChanged, true);
            app.DP_SplitTestSetForDynamicSwitchCheckBox.Enable = 'off';
            app.DP_SplitTestSetForDynamicSwitchCheckBox.Text = 'Split Test Set for Dynamic Switch';
            app.DP_RatioTestSwitchEditField = uieditfield(app.DP_DatasetProcessingPanel, 'numeric');
            app.DP_RatioTestSwitchEditField.Limits = [0.01 0.99];
            app.DP_RatioTestSwitchEditField.ValueChangedFcn = createCallbackFcn(app, @DP_RatioTestSwitchEditFieldValueChanged, true);
            app.DP_RatioTestSwitchEditField.Enable = 'off';
            app.DP_RatioTestSwitchEditField.Value = 0.5;
            app.DP_RatioTestSwitchLabel = uilabel(app.DP_DatasetProcessingPanel);
            app.DP_RatioTestSwitchLabel.Enable = 'off';
            app.DP_RatioTestSwitchLabel.Text = 'Ratio:';
            app.DP_AugmentedTrainingCheckBock = uicheckbox(app.DP_DatasetProcessingPanel);
            app.DP_AugmentedTrainingCheckBock.ValueChangedFcn = createCallbackFcn(app, @DP_AugmentedTrainingCheckBockValueChanged, true);
            app.DP_AugmentedTrainingCheckBock.Visible = 'off';
            app.DP_AugmentedTrainingCheckBock.Text = 'Augmented training';
            app.DP_AugmentationIntensitySlider = uislider(app.DP_DatasetProcessingPanel);
            app.DP_AugmentationIntensitySlider.ValueChangedFcn = createCallbackFcn(app, @DP_AugmentationIntensitySliderValueChanged, true);
            app.DP_AugmentationIntensitySlider.Visible = 'off';
            app.PreprocessingMethodLabel = uilabel(app.DP_DatasetProcessingPanel);
            app.PreprocessingMethodLabel.Text = 'Preprocessing Method:';
            app.DP_PreprocessingMethodDropDown = uidropdown(app.DP_DatasetProcessingPanel);
            app.DP_PreprocessingMethodDropDown.Items = {'min_max_normalization', 'standardization', 'raw_data'};
            app.DP_PreprocessingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DP_PreprocessingMethodDropDownValueChanged, true);
            app.DP_PreprocessingMethodDropDown.Value = 'min_max_normalization';
            app.AugmentationModeDropDownLabel = uilabel(app.DP_DatasetProcessingPanel);
            app.AugmentationModeDropDownLabel.Text = 'Augmentation Mode:';
            app.DP_AugmentationModeDropDown = uidropdown(app.DP_DatasetProcessingPanel);
            app.DP_AugmentationModeDropDown.Items = {'none', 'white_noise', 'random_walk', 'global_shift', 'attenuate_extremum'};
            app.DP_AugmentationModeDropDown.ValueChangedFcn = createCallbackFcn(app, @DP_AugmentationModeDropDownValueChanged, true);
            app.DP_AugmentationModeDropDown.Value = 'none';
            app.DP_AugmentationIntensityLabel = uilabel(app.DP_DatasetProcessingPanel);
            app.DP_AugmentationIntensityLabel.Visible = 'off';
            app.DP_AugmentationIntensityLabel.Text = 'Intensity';
            app.TrainingAndDetectionTab = uitab(app.MainPanel);
            app.TrainingAndDetectionTab.Title = '1.2 Training and Detection';
            app.TrainingAndDetectionPanel = uipanel(app.TrainingAndDetectionTab);
            app.TD_ModelsPanel = uipanel(app.TrainingAndDetectionPanel);
            app.TD_ModelsPanel.Title = 'Models';
            app.TD_ModelsListBox = uilistbox(app.TD_ModelsPanel);
            app.TD_ModelsListBox.Items = {};
            app.TD_ModelsListBox.Multiselect = 'on';
            app.TD_ModelsListBox.Value = {};
            app.TD_ExportTrainedButton = uibutton(app.TD_ModelsPanel, 'push');
            app.TD_ExportTrainedButton.ButtonPushedFcn = createCallbackFcn(app, @TD_ExportTrainedButtonPushed, true);
            app.TD_ExportTrainedButton.Text = 'Export Trained';
            app.TD_ImportTrainedButton = uibutton(app.TD_ModelsPanel, 'push');
            app.TD_ImportTrainedButton.ButtonPushedFcn = createCallbackFcn(app, @TD_ImportTrainedButtonPushed, true);
            app.TD_ImportTrainedButton.Text = 'Import Trained';
            app.SortbyLabel = uilabel(app.TD_ModelsPanel);
            app.SortbyLabel.Text = 'Rank by:';
            app.TD_SortByDropDown = uidropdown(app.TD_ModelsPanel);
            app.TD_SortByDropDown.Items = {};
            app.TD_SortByDropDown.ValueChangedFcn = createCallbackFcn(app, @TD_SortByDropDownValueChanged, true);
            app.TD_SortByDropDown.Value = {};
            app.TD_LoadConfigButton = uibutton(app.TD_ModelsPanel, 'push');
            app.TD_LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @TD_LoadConfigButtonPushed, true);
            app.TD_LoadConfigButton.Text = 'Load Config';
            app.TD_LoadDefaultButton = uibutton(app.TD_ModelsPanel, 'push');
            app.TD_LoadDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @TD_LoadDefaultButtonPushed, true);
            app.TD_LoadDefaultButton.Text = 'Load Default';
            app.TD_LoadManuallyButton = uibutton(app.TD_ModelsPanel, 'push');
            app.TD_LoadManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @TD_LoadManuallyButtonPushed, true);
            app.TD_LoadManuallyButton.Text = 'Load Manually';
            app.TD_OptimizeCheckBox = uicheckbox(app.TD_ModelsPanel);
            app.TD_OptimizeCheckBox.Text = 'Optimize';
            app.TD_TrainingPlotsCheckBox = uicheckbox(app.TD_ModelsPanel);
            app.TD_TrainingPlotsCheckBox.Text = 'Training Plots';
            app.TD_TrainingPlotsCheckBox.Value = true;
            app.TD_FitSelectionButton = uibutton(app.TD_ModelsPanel, 'push');
            app.TD_FitSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @TD_FitSelectionButtonPushed, true);
            app.TD_FitSelectionButton.Text = 'Fit Selection';
            app.TD_FitAllButton = uibutton(app.TD_ModelsPanel, 'push');
            app.TD_FitAllButton.ButtonPushedFcn = createCallbackFcn(app, @TD_FitAllButtonPushed, true);
            app.TD_FitAllButton.Text = 'Fit All';
            app.TD_ClearAllButton = uibutton(app.TD_ModelsPanel, 'push');
            app.TD_ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @TD_ClearAllButtonPushed, true);
            app.TD_ClearAllButton.Text = 'Clear All';
            app.TD_ExportConfigButton = uibutton(app.TD_ModelsPanel, 'push');
            app.TD_ExportConfigButton.ButtonPushedFcn = createCallbackFcn(app, @TD_ExportConfigButtonPushed, true);
            app.TD_ExportConfigButton.Text = 'Export Config';
            app.TD_DetectionPanel = uipanel(app.TrainingAndDetectionPanel);
            app.TD_DetectionPanel.Title = 'Detection';
            app.TD_DetectionTabGroup = uitabgroup(app.TD_DetectionPanel);
            app.TD_PlotsTab = uitab(app.TD_DetectionTabGroup);
            app.TD_PlotsTab.Title = 'Plots';
            app.TD_PlotsAnomalyScoresUIAxes = uiaxes(app.TD_PlotsTab);
            title(app.TD_PlotsAnomalyScoresUIAxes, 'Anomaly Scores')
            xlabel(app.TD_PlotsAnomalyScoresUIAxes, 'Timestamp')
            ylabel(app.TD_PlotsAnomalyScoresUIAxes, 'Score')
            zlabel(app.TD_PlotsAnomalyScoresUIAxes, 'Z')
            app.TD_PlotsDataUIAxes = uiaxes(app.TD_PlotsTab);
            title(app.TD_PlotsDataUIAxes, 'Data + Anomalies')
            xlabel(app.TD_PlotsDataUIAxes, 'Timestamp')
            ylabel(app.TD_PlotsDataUIAxes, 'Value')
            zlabel(app.TD_PlotsDataUIAxes, 'Z')
            app.TD_PlotsScoresUITable = uitable(app.TD_PlotsTab);
            app.TD_PlotsScoresUITable.ColumnName = '';
            app.TD_PlotsScoresUITable.RowName = {};
            app.TD_DetectionShownForLabel = uilabel(app.TD_PlotsTab);
            app.TD_DetectionShownForLabel.Text = 'Detection Shown for: None';
            app.TD_DetectionPerformanceMetricsLabel = uilabel(app.TD_PlotsTab);
            app.TD_DetectionPerformanceMetricsLabel.Text = 'Detection Performance Metrics:';
            app.TD_TableTab = uitab(app.TD_DetectionTabGroup);
            app.TD_TableTab.Title = 'Scores (selected File)';
            app.TD_ScoresUITable = uitable(app.TD_TableTab);
            app.TD_ScoresUITable.ColumnName = {'Metric'};
            app.TD_ScoresUITable.RowName = {};
            app.TD_ScoresExportTableButton = uibutton(app.TD_TableTab, 'push');
            app.TD_ScoresExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @TD_ScoresExportTableButtonPushed, true);
            app.TD_ScoresExportTableButton.Text = 'Export Table';
            app.TD_ScoresRowwiseMaximaLabel = uilabel(app.TD_TableTab);
            app.TD_ScoresRowwiseMaximaLabel.Text = 'Rowwise maxima';
            app.TD_ScoresColumnwiseMaximaLabel = uilabel(app.TD_TableTab);
            app.TD_ScoresColumnwiseMaximaLabel.Text = 'Columnwise maxima';
            app.TD_ScoresLegendLabel = uilabel(app.TD_TableTab);
            app.TD_ScoresLegendLabel.Text = 'Legend:';
            app.TD_AverageScoresTab = uitab(app.TD_DetectionTabGroup);
            app.TD_AverageScoresTab.Title = 'Average Scores';
            app.TD_AverageScoresUITable = uitable(app.TD_AverageScoresTab);
            app.TD_AverageScoresUITable.ColumnName = {'Metric'};
            app.TD_AverageScoresUITable.RowName = {};
            app.TD_AverageScoresExportTableButton = uibutton(app.TD_AverageScoresTab, 'push');
            app.TD_AverageScoresExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @TD_AverageScoresExportTableButtonPushed, true);
            app.TD_AverageScoresExportTableButton.Text = 'Export Table';
            app.TD_AverageScoresRowwiseMaximaLabel = uilabel(app.TD_AverageScoresTab);
            app.TD_AverageScoresRowwiseMaximaLabel.Text = 'Rowwise maxima';
            app.TD_AverageScoresColumnwiseMaximaLabel = uilabel(app.TD_AverageScoresTab);
            app.TD_AverageScoresColumnwiseMaximaLabel.Text = 'Columnwise maxima';
            app.TD_AverageScoresLegendLabel = uilabel(app.TD_AverageScoresTab);
            app.TD_AverageScoresLegendLabel.Text = 'Legend:';
            app.TD_ComputationTimeTab = uitab(app.TD_DetectionTabGroup);
            app.TD_ComputationTimeTab.Title = 'Computation Time';
            app.TD_CompTimeUIAxes = uiaxes(app.TD_ComputationTimeTab);
            title(app.TD_CompTimeUIAxes, 'Mean Computation Time per Window')
            xlabel(app.TD_CompTimeUIAxes, 'Models sorted')
            ylabel(app.TD_CompTimeUIAxes, 'Metric value')
            zlabel(app.TD_CompTimeUIAxes, 'Z')
            app.TD_CompTimeMetricSelectionButton = uibutton(app.TD_ComputationTimeTab, 'push');
            app.TD_CompTimeMetricSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @TD_CompTimeMetricSelectionButtonPushed, true);
            app.TD_CompTimeMetricSelectionButton.Text = 'Metric Selection';
            app.TD_FileSelectionButtonGroup = uibuttongroup(app.TD_DetectionPanel);
            app.TD_FileSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TD_FileSelectionButtonGroupSelectionChanged, true);
            app.TD_FileSelectionButtonGroup.Title = 'File Selection';
            app.TD_FileSelectionTinyDataPreviewUIAxes = uiaxes(app.TD_FileSelectionButtonGroup);
            app.TD_FileSelectionTinyDataPreviewUIAxes.XTick = [];
            app.TD_FileSelectionTinyDataPreviewUIAxes.YTick = [];
            app.TD_FileSelectionTinyDataPreviewUIAxes.Visible = 'off';
            app.TD_FileSelectionAllFilesButton = uiradiobutton(app.TD_FileSelectionButtonGroup);
            app.TD_FileSelectionAllFilesButton.Text = 'All Files';
            app.TD_FileSelectionAllFilesButton.Value = true;
            app.TD_FileSelectionSelectionButton = uiradiobutton(app.TD_FileSelectionButtonGroup);
            app.TD_FileSelectionSelectionButton.Text = 'Selection';
            app.TD_FileSelectionFileLabel = uilabel(app.TD_FileSelectionButtonGroup);
            app.TD_FileSelectionFileLabel.Enable = 'off';
            app.TD_FileSelectionFileLabel.Text = 'File:';
            app.TD_TestDataFileDropDown = uidropdown(app.TD_FileSelectionButtonGroup);
            app.TD_TestDataFileDropDown.Items = {};
            app.TD_TestDataFileDropDown.ValueChangedFcn = createCallbackFcn(app, @TD_TestDataFileDropDownValueChanged, true);
            app.TD_TestDataFileDropDown.Enable = 'off';
            app.TD_TestDataFileDropDown.Value = {};
            app.TD_RunDetectionButton = uibutton(app.TD_DetectionPanel, 'push');
            app.TD_RunDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @TD_RunDetectionButtonPushed, true);
            app.TD_RunDetectionButton.Text = {'Run'; 'Detection'};
            app.TD_ZRangeLabel = uilabel(app.TD_DetectionPanel);
            app.TD_ZRangeLabel.Enable = 'off';
            app.TD_ZRangeLabel.Text = 'Z-Range:';
            app.TD_WindowSizeLabel = uilabel(app.TD_DetectionPanel);
            app.TD_WindowSizeLabel.Enable = 'off';
            app.TD_WindowSizeLabel.Text = 'Window Size (%):';
            app.TD_AnomalyPaddingLabel = uilabel(app.TD_DetectionPanel);
            app.TD_AnomalyPaddingLabel.Enable = 'off';
            app.TD_AnomalyPaddingLabel.Text = 'Anomaly Padding:';
            app.TD_ZRangeSlider = uislider(app.TD_DetectionPanel);
            app.TD_ZRangeSlider.Limits = [1 10];
            app.TD_ZRangeSlider.ValueChangedFcn = createCallbackFcn(app, @TD_ZRangeSliderValueChanged, true);
            app.TD_ZRangeSlider.Enable = 'off';
            app.TD_ZRangeSlider.Value = 1;
            app.TD_WindowSizeSlider = uislider(app.TD_DetectionPanel);
            app.TD_WindowSizeSlider.Limits = [0 50];
            app.TD_WindowSizeSlider.ValueChangedFcn = createCallbackFcn(app, @TD_WindowSizeSliderValueChanged, true);
            app.TD_WindowSizeSlider.Enable = 'off';
            app.TD_AnomalyPaddingSlider = uislider(app.TD_DetectionPanel);
            app.TD_AnomalyPaddingSlider.Limits = [0 1000];
            app.TD_AnomalyPaddingSlider.ValueChangedFcn = createCallbackFcn(app, @TD_AnomalyPaddingSliderValueChanged, true);
            app.TD_AnomalyPaddingSlider.Enable = 'off';
            app.ThresholdDropDownLabel = uilabel(app.TD_DetectionPanel);
            app.ThresholdDropDownLabel.Text = 'Threshold:';
            app.TD_ThresholdDropDown = uidropdown(app.TD_DetectionPanel);
            app.TD_ThresholdDropDown.Items = {};
            app.TD_ThresholdDropDown.ValueChangedFcn = createCallbackFcn(app, @TD_ThresholdDropDownValueChanged, true);
            app.TD_ThresholdDropDown.Tooltip = {'The threshold used for converting anomaly scores into binary labels. To enable other thresholds, got to the Settings > Threshold Selection'};
            app.TD_ThresholdDropDown.Value = {};
            app.TD_ModelSelectionButtonGroup = uibuttongroup(app.TD_DetectionPanel);
            app.TD_ModelSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TD_ModelSelectionButtonGroupSelectionChanged, true);
            app.TD_ModelSelectionButtonGroup.Title = 'Model Selection';
            app.TD_ModelSelectionAllButton = uiradiobutton(app.TD_ModelSelectionButtonGroup);
            app.TD_ModelSelectionAllButton.Text = 'All';
            app.TD_ModelSelectionAllButton.Value = true;
            app.TD_ModelSelectionSelectionButton = uiradiobutton(app.TD_ModelSelectionButtonGroup);
            app.TD_ModelSelectionSelectionButton.Text = 'Selection';
            app.TD_GetComputationTimeCheckBox = uicheckbox(app.TD_DetectionPanel);
            app.TD_GetComputationTimeCheckBox.Text = 'Get Computation Time';
            app.TD_UpdateThresholdOnlyCheckBox = uicheckbox(app.TD_DetectionPanel);
            app.TD_UpdateThresholdOnlyCheckBox.Text = 'Update Threshold Only';
            app.TD_OnlineSimulationCheckBox = uicheckbox(app.TD_DetectionPanel);
            app.TD_OnlineSimulationCheckBox.Text = 'Online Simulation';
            app.DynamicSwitchTab = uitab(app.MainPanel);
            app.DynamicSwitchTab.Title = '1.3 (c) Dynamic Switch';
            app.DynamicSwitchPanel = uipanel(app.DynamicSwitchTab);
            app.DS_PreprocessedTestSwitchDatasetPreviewUIAxes = uiaxes(app.DynamicSwitchPanel);
            title(app.DS_PreprocessedTestSwitchDatasetPreviewUIAxes, 'Test Switch Dataset (processed)')
            xlabel(app.DS_PreprocessedTestSwitchDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.DS_PreprocessedTestSwitchDatasetPreviewUIAxes, 'Value')
            zlabel(app.DS_PreprocessedTestSwitchDatasetPreviewUIAxes, 'Z')
            app.DS_ResultsUITable = uitable(app.DynamicSwitchPanel);
            app.DS_ResultsUITable.ColumnName = '';
            app.DS_ResultsUITable.RowName = {};
            app.DS_ResultsLabel = uilabel(app.DynamicSwitchPanel);
            app.DS_ResultsLabel.Text = 'Results:';
            app.DS_TrainingAndEvaluationPanel = uipanel(app.DynamicSwitchPanel);
            app.DS_TrainingAndEvaluationPanel.Tooltip = {'This panel can be used to train and test a classification network which can select the optimal model for each file of the testing dataset.'};
            app.DS_TrainingAndEvaluationPanel.Title = 'Training and Evaluation';
            app.DS_RunEvaluationsButton = uibutton(app.DS_TrainingAndEvaluationPanel, 'push');
            app.DS_RunEvaluationsButton.ButtonPushedFcn = createCallbackFcn(app, @DS_RunEvaluationsButtonPushed, true);
            app.DS_RunEvaluationsButton.Enable = 'off';
            app.DS_RunEvaluationsButton.Text = 'Run Evaluations';
            app.DS_TrainClassifierButton = uibutton(app.DS_TrainingAndEvaluationPanel, 'push');
            app.DS_TrainClassifierButton.ButtonPushedFcn = createCallbackFcn(app, @DS_TrainClassifierButtonPushed, true);
            app.DS_TrainClassifierButton.Enable = 'off';
            app.DS_TrainClassifierButton.Text = 'Train Classifier';
            app.ScoreforAutolabelingLabel = uilabel(app.DS_TrainingAndEvaluationPanel);
            app.ScoreforAutolabelingLabel.Text = 'Metric for Comparing Models:';
            app.DS_MetricDropDown = uidropdown(app.DS_TrainingAndEvaluationPanel);
            app.DS_MetricDropDown.Items = {};
            app.DS_MetricDropDown.Value = {};
            app.DS_TrainingDataLabelsUITable = uitable(app.DS_TrainingAndEvaluationPanel);
            app.DS_TrainingDataLabelsUITable.ColumnName = {'File'; 'Best Model'};
            app.DS_TrainingDataLabelsUITable.RowName = {};
            app.DS_TrainingDataLabelsLabel = uilabel(app.DS_TrainingAndEvaluationPanel);
            app.DS_TrainingDataLabelsLabel.Text = 'Training Data Labels';
            app.DS_TestingDataPredictionsUITable = uitable(app.DS_TrainingAndEvaluationPanel);
            app.DS_TestingDataPredictionsUITable.ColumnName = {'File'; 'Best Model'};
            app.DS_TestingDataPredictionsUITable.RowName = {};
            app.DS_TestingDataPredictionsLabel = uilabel(app.DS_TrainingAndEvaluationPanel);
            app.DS_TestingDataPredictionsLabel.Text = 'Testing Data Predictions';
            app.DS_ExportButton = uibutton(app.DynamicSwitchPanel, 'push');
            app.DS_ExportButton.ButtonPushedFcn = createCallbackFcn(app, @DS_ExportButtonPushed, true);
            app.DS_ExportButton.Text = 'Export Table';
            app.SelectedChannelLabel_6 = uilabel(app.DynamicSwitchPanel);
            app.SelectedChannelLabel_6.Text = 'Selected Channel:';
            app.DS_SelectedChannelTestSwitchDropDown = uidropdown(app.DynamicSwitchPanel);
            app.DS_SelectedChannelTestSwitchDropDown.Items = {};
            app.DS_SelectedChannelTestSwitchDropDown.ValueChangedFcn = createCallbackFcn(app, @DS_SelectedChannelTestSwitchDropDownValueChanged, true);
            app.DS_SelectedChannelTestSwitchDropDown.Value = {};
            app.SelectedFileLabel_6 = uilabel(app.DynamicSwitchPanel);
            app.SelectedFileLabel_6.Text = 'Selected File:';
            app.DS_SelectedFileTestSwitchDropDown = uidropdown(app.DynamicSwitchPanel);
            app.DS_SelectedFileTestSwitchDropDown.Items = {};
            app.DS_SelectedFileTestSwitchDropDown.ValueChangedFcn = createCallbackFcn(app, @DS_SelectedFileTestSwitchDropDownValueChanged, true);
            app.DS_SelectedFileTestSwitchDropDown.Value = {};
            app.AutoRunTab = uitab(app.MainPanel);
            app.AutoRunTab.Title = '2. Auto Run';
            app.AutoRunPanel = uipanel(app.AutoRunTab);
            app.AR_AutoTrainingAndEvaluationPanel = uipanel(app.AutoRunPanel);
            app.AR_AutoTrainingAndEvaluationPanel.Title = 'Auto Training and Evaluation';
            app.AR_RunEvaluationButton = uibutton(app.AR_AutoTrainingAndEvaluationPanel, 'push');
            app.AR_RunEvaluationButton.ButtonPushedFcn = createCallbackFcn(app, @AR_RunEvaluationButtonPushed, true);
            app.AR_RunEvaluationButton.Text = 'Run Evaluation';
            app.AR_BrowseDatasetButton = uibutton(app.AR_AutoTrainingAndEvaluationPanel, 'push');
            app.AR_BrowseDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @AR_BrowseDatasetButtonPushed, true);
            app.AR_BrowseDatasetButton.Text = 'Browse...';
            app.AR_DatasetFolderLabel = uilabel(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_DatasetFolderLabel.Text = 'Dataset Folder:';
            app.AR_DatasetFolderEditField = uieditfield(app.AR_AutoTrainingAndEvaluationPanel, 'text');
            app.AR_RatioTestValEditField = uieditfield(app.AR_AutoTrainingAndEvaluationPanel, 'numeric');
            app.AR_RatioTestValEditField.Limits = [0.01 0.99];
            app.AR_RatioTestValEditField.Enable = 'off';
            app.AR_RatioTestValEditField.Value = 0.5;
            app.AR_UseAnomalousValidationSetCheckBox = uicheckbox(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_UseAnomalousValidationSetCheckBox.ValueChangedFcn = createCallbackFcn(app, @AR_UseAnomalousValidationSetCheckBoxValueChanged, true);
            app.AR_UseAnomalousValidationSetCheckBox.Text = 'Use anomalous Validation Set';
            app.AR_RatioTestValLabel = uilabel(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_RatioTestValLabel.Enable = 'off';
            app.AR_RatioTestValLabel.Text = 'Ratio:';
            app.AR_TrainingPlotsCheckBox = uicheckbox(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_TrainingPlotsCheckBox.Text = 'Training Plots';
            app.AR_AugmentedTrainingCheckBock = uicheckbox(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_AugmentedTrainingCheckBock.Visible = 'off';
            app.AR_AugmentedTrainingCheckBock.Text = 'Augmented training';
            app.AR_IntensitySlider = uislider(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_IntensitySlider.Visible = 'off';
            app.AR_IntensityLabel = uilabel(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_IntensityLabel.Visible = 'off';
            app.AR_IntensityLabel.Text = 'Intensity';
            app.PreprocessingMethodLabel_2 = uilabel(app.AR_AutoTrainingAndEvaluationPanel);
            app.PreprocessingMethodLabel_2.Text = 'Preprocessing Method:';
            app.AR_PreprocessingMethodDropDown = uidropdown(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_PreprocessingMethodDropDown.Items = {'Rescale [0, 1]', 'Standardize', 'Raw Data'};
            app.AR_PreprocessingMethodDropDown.Value = 'Rescale [0, 1]';
            app.AugmentationModeDropDownLabel_2 = uilabel(app.AR_AutoTrainingAndEvaluationPanel);
            app.AugmentationModeDropDownLabel_2.Text = 'Augmentation Mode:';
            app.AR_AugmentationModeDropDown = uidropdown(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_AugmentationModeDropDown.Items = {'none', 'white noise', 'random walk', 'global shift', 'attenuate extremum'};
            app.AR_AugmentationModeDropDown.ValueChangedFcn = createCallbackFcn(app, @AR_AugmentationModeDropDownValueChanged, true);
            app.AR_AugmentationModeDropDown.Value = 'none';
            app.AR_VisualizeDataButton = uibutton(app.AR_AutoTrainingAndEvaluationPanel, 'push');
            app.AR_VisualizeDataButton.ButtonPushedFcn = createCallbackFcn(app, @AR_VisualizeDataButtonPushed, true);
            app.AR_VisualizeDataButton.Text = 'Visualize Data';
            app.AR_GetComputationTimeCheckBox = uicheckbox(app.AR_AutoTrainingAndEvaluationPanel);
            app.AR_GetComputationTimeCheckBox.Text = 'Get Computation Time';
            app.AR_ResultsUITable = uitable(app.AutoRunPanel);
            app.AR_ResultsUITable.ColumnName = '';
            app.AR_ResultsUITable.RowName = {};
            app.AR_ResultsLabel = uilabel(app.AutoRunPanel);
            app.AR_ResultsLabel.Text = 'Average Scores (See Folder Auto_Run_Results for Rest):';
            app.AR_ExportTableButton = uibutton(app.AutoRunPanel, 'push');
            app.AR_ExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @AR_ExportTableButtonPushed, true);
            app.AR_ExportTableButton.Text = 'Export Table';
            app.TD_ContextMenu = uicontextmenu(app.TSADPlatformUIFigure);
            app.TD_ViewUpdateModelParametersMenu = uimenu(app.TD_ContextMenu);
            app.TD_ViewUpdateModelParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @TD_ViewUpdateModelParametersMenuSelected, true);
            app.TD_ViewUpdateModelParametersMenu.Text = 'View/Update Model Parameters';
            app.TD_ShowDetectionMenu = uimenu(app.TD_ContextMenu);
            app.TD_ShowDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @TD_ShowDetectionMenuSelected, true);
            app.TD_ShowDetectionMenu.Text = 'Show Detection';
            app.TD_ChangeScoringFunctionMenu = uimenu(app.TD_ContextMenu);
            app.TD_ChangeScoringFunctionMenu.MenuSelectedFcn = createCallbackFcn(app, @TD_ChangeScoringFunctionMenuSelected, true);
            app.TD_ChangeScoringFunctionMenu.Text = 'Change Scoring Function';
            app.TD_UpdateStaticThresholdsMenu = uimenu(app.TD_ContextMenu);
            app.TD_UpdateStaticThresholdsMenu.MenuSelectedFcn = createCallbackFcn(app, @TD_UpdateStaticThresholdsMenuSelected, true);
            app.TD_UpdateStaticThresholdsMenu.Text = 'Update Static Thresholds';
            app.TD_DeleteMenu = uimenu(app.TD_ContextMenu);
            app.TD_DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @TD_DeleteMenuSelected, true);
            app.TD_DeleteMenu.Text = 'Delete';
            % Assign app.TD_ContextMenu
            app.TD_ModelsListBox.ContextMenu = app.TD_ContextMenu;
            % Show the figure after all components are created
            app.TSADPlatformUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TSADPlatform

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TSADPlatformUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.TSADPlatformUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TSADPlatformUIFigure)
        end
    end
end